classdef Shock_Test_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainOperationsTab              matlab.ui.container.Tab
        LocationofdatafileEditField    matlab.ui.control.EditField
        LocationofdatafileEditFieldLabel  matlab.ui.control.Label
        Spec_SRSEditField              matlab.ui.control.NumericEditField
        Spec_SRSEditFieldLabel         matlab.ui.control.Label
        Spec_freq_zEditField_2         matlab.ui.control.NumericEditField
        Spec_freq_zEditField_2Label    matlab.ui.control.Label
        Spec_freq_yEditField_2         matlab.ui.control.NumericEditField
        Spec_freq_yEditField_2Label    matlab.ui.control.Label
        Spec_SRS_xEditField            matlab.ui.control.NumericEditField
        Spec_SRS_xEditFieldLabel       matlab.ui.control.Label
        Spec_freq_zEditField           matlab.ui.control.NumericEditField
        Spec_freq_zEditFieldLabel      matlab.ui.control.Label
        Spec_freq_yEditField           matlab.ui.control.NumericEditField
        Spec_freq_yEditFieldLabel      matlab.ui.control.Label
        Spec_freq_xEditField           matlab.ui.control.NumericEditField
        Spec_freq_xEditFieldLabel      matlab.ui.control.Label
        Spec_freqEditField             matlab.ui.control.NumericEditField
        Spec_freqEditFieldLabel        matlab.ui.control.Label
        sample_durationSpinner         matlab.ui.control.Spinner
        sample_durationSpinnerLabel    matlab.ui.control.Label
        sample_rateSpinner             matlab.ui.control.Spinner
        sample_rateSpinnerLabel        matlab.ui.control.Label
        DEV2_sensor_CH2_gainSpinner    matlab.ui.control.Spinner
        DEV2_sensor_CH2_gainSpinnerLabel  matlab.ui.control.Label
        DEV2_sensor_CH1_gainSpinner    matlab.ui.control.Spinner
        DEV2_sensor_CH1_gainSpinnerLabel  matlab.ui.control.Label
        DEV2_sensor_CH0_gainSpinner    matlab.ui.control.Spinner
        DEV2_sensor_CH0_gainSpinnerLabel  matlab.ui.control.Label
        DEV2_sensor_CH2EditField       matlab.ui.control.EditField
        DEV2_sensor_CH2EditFieldLabel  matlab.ui.control.Label
        DEV2_sensor_CH1EditField       matlab.ui.control.EditField
        DEV2_sensor_CH1EditFieldLabel  matlab.ui.control.Label
        DEV2_sensor_CH0EditField       matlab.ui.control.EditField
        DEV2_sensor_CH0EditFieldLabel  matlab.ui.control.Label
        NI_DAQ2nameEditField           matlab.ui.control.EditField
        NI_DAQ2nameEditFieldLabel      matlab.ui.control.Label
        DEV1_sensor_CH2_gainSpinner    matlab.ui.control.Spinner
        DEV1_sensor_CH2_gainSpinnerLabel  matlab.ui.control.Label
        DEV1_sensor_CH1_gainSpinner    matlab.ui.control.Spinner
        DEV1_sensor_CH1_gainSpinnerLabel  matlab.ui.control.Label
        DEV1_sensor_CH0_gainSpinner    matlab.ui.control.Spinner
        DEV1_sensor_CH0_gainSpinnerLabel  matlab.ui.control.Label
        DEV1_sensor_CH2EditField       matlab.ui.control.EditField
        DEV1_sensor_CH2EditFieldLabel  matlab.ui.control.Label
        DEV1_sensor_CH1EditField       matlab.ui.control.EditField
        DEV1_sensor_CH1EditFieldLabel  matlab.ui.control.Label
        DEV1_sensor_CH0EditField       matlab.ui.control.EditField
        DEV1_sensor_CH0EditFieldLabel  matlab.ui.control.Label
        NI_DAQ1nameEditField           matlab.ui.control.EditField
        NI_DAQ1nameEditFieldLabel      matlab.ui.control.Label
        EditField_16                   matlab.ui.control.NumericEditField
        EditField_15                   matlab.ui.control.NumericEditField
        EditField_14                   matlab.ui.control.NumericEditField
        EditField_13                   matlab.ui.control.NumericEditField
        EditField_12                   matlab.ui.control.NumericEditField
        EditField_11                   matlab.ui.control.NumericEditField
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        LaunchButton                   matlab.ui.control.Button
        WaitLamp                       matlab.ui.control.Lamp
        StatusLabel                    matlab.ui.control.Label
        MainPlotLabel                  matlab.ui.control.Label
        DescriptionforlogfileCheckBox  matlab.ui.control.CheckBox
        PlotDataCheckBox               matlab.ui.control.CheckBox
        sLabel                         matlab.ui.control.Label
        kHLabel                        matlab.ui.control.Label
        ConfigurationsLabel            matlab.ui.control.Label
        Device2Label                   matlab.ui.control.Label
        Device1Label                   matlab.ui.control.Label
        UseDevice2CheckBox             matlab.ui.control.CheckBox
        MainGraph                      matlab.ui.control.UIAxes
        IndividualGraphsTab            matlab.ui.container.Tab
        RawDataGraphsTab               matlab.ui.container.Tab
        Device2Label_2                 matlab.ui.control.Label
        Device1Label_2                 matlab.ui.control.Label
        UIAxes_12                      matlab.ui.control.UIAxes
        UIAxes_11                      matlab.ui.control.UIAxes
        UIAxes_10                      matlab.ui.control.UIAxes
        UIAxes_9                       matlab.ui.control.UIAxes
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device_list = daqlist("ni");
        number_of_expected_devices = 2;
        Device1_ready = false;
        Device2_ready = false;
    end
    
    methods (Access = private)
        
        function impact_points = Edge_detec(app,all_data,selectedChannels,sensor_numeric_inputs)
            % Edge detection to specify where to analyse the data
            % * all_data is all the raw data from the devices
            % * sensor_text_inputs is a 6x1 vector containing the user
            % names for each channel
            % * sensor_numeric_inputs is a 6x1 vector containing users
            % defined gains for each channel
            % * impact_points is a 6x1 vector that has the outputs of all
            % the impact points for each channel if channel is not selected
            % then it will default to 0

            % Declaring array
            impact_points = zeros(1, 6);
            
            % Looping over all channels
            for channel = 1:6
                
                % Checking if they are selected by user
                if selectedChannels(channel)
                    
                    % Finding the difference between sucssecive data points
                    % of each channel
                    test = diff(all_data(:,channel)*sensor_numeric_inputs(channel));
                    
                    % Looping over the whole data
                    for i_loop=1:length(test)

                        if(abs(test(i_loop)) > 6) && i_loop > 1000
                            impact_points(channel) = i_loop;
                            break

                        end
                    end
                end
            end
            % Debugging
            app.LaunchButton.Text = num2str(impact_points(1));
            pause(2)
            app.LaunchButton.Text = num2str(impact_points(2));
            pause(2)
            app.LaunchButton.Text = num2str(impact_points(3));
            pause(2)
            app.LaunchButton.Text = num2str(impact_points(4));
            pause(2)
            app.LaunchButton.Text = num2str(impact_points(5));
            pause(2)
            app.LaunchButton.Text = num2str(impact_points(6));
            pause(2)

        end
        
        function special_median = Non_zero_median(app,list)
            % Calculates the median of the non-zero entries
            % * list is a 1D array of numbers 
            % * special_median is the integer median (rounded down) 
            % of the non-zero entries

            % New list without zero entries
            non_zero_list = list(list ~= 0);

            % Median is calculated with new list 
            special_median = floor(median(non_zero_list));

        end
        
        function [x_pos,x_neg,fn] = SRS_calc(app,Input)
            % disp(' ')
            % disp(' srs.m   ver 2.0   July 3, 2006')
            % disp(' by Tom Irvine   Email: tomirvine@aol.com')
            % disp(' ')
            % disp(' This program calculates the shock response spectrum')
            % disp(' of an acceleration time history, which is pre-loaded into Matlab.')
            % disp(' The time history must have two columns: time(sec) & acceleration')
            % disp(' ')

            THM = Input;

            t=double(THM(:,1));
            y=double(THM(:,2));

            tmx=max(t);
            tmi=min(t);
            n = length(y);

            dt=(tmx-tmi)/(n-1);
            sr=1./dt;

            fn(1) = 20;
            if fn(1)>sr/30.
                fn(1)=sr/30.;
            end
           
            Q = 10;
            damp=1./(2.*Q);

            ialgorithm=1;

            tmax=(tmx-tmi) + 1./fn(1);
            limit = round( tmax/dt );
            n=limit;
            yy=zeros(1,limit);
            for i=1:length(y)
                    yy(i)=y(i);
            end    
            for j=1:1000

                omega=2.*pi*fn(j);
                omegad=omega*sqrt(1.-(damp^2));
                cosd=cos(omegad*dt);
                sind=sin(omegad*dt);
                domegadt=damp*omega*dt;

                if(ialgorithm==1)
                    a1(j)=2.*exp(-domegadt)*cosd;
                    a2(j)=-exp(-2.*domegadt);
                    b1(j)=2.*domegadt;
                    b2(j)=omega*dt*exp(-domegadt);
                    b2(j)=b2(j)*( (omega/omegad)*(1.-2.*(damp^2))*sind -2.*damp*cosd );
                    b3(j)=0;

                else
	                E=exp(-damp*omega*dt);
		            K=omegad*dt;
		            C=E*cos(K);
		            S=E*sin(K);
		            Sp=S/K;

	                a1(j)=2*C;
		            a2(j)=-E^2;
		            b1(j)=1.-Sp;
		            b2(j)=2.*(Sp-C);
		            b3(j)=E^2-Sp;
                end
                forward=[ b1(j),  b2(j),  b3(j) ];    
                back   =[     1, -a1(j), -a2(j) ];    
    
                resp=filter(forward,back,yy);

                x_pos(j)= max(resp);
                x_neg(j)= min(resp);
  
                jnum=j; 
                if  fn(j) > sr/8.
                    break
                end
                fn(j+1)=fn(1)*(2. ^ (j*(1./12.)));    
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaunchButton
        function Launch_callback(app, event)
            
            % File location for the program, this wil be called the repository from now
            filename = 'H:\MATLAB\New_Code';

            % Changes filename while not changing variable name
            filename = [filename,'\'];

            % Starts the config for the dataloggers
            session = daq.createSession('ni');
            
            % User selected sample rate
            session.Rate = app.sample_rateSpinner.Value*1000;
            
            % User selected sample duration
            session.DurationInSeconds = app.sample_durationSpinner.Value;
            
            % Defining a list of selected channels
            selectedChannels = false(1, 6);
            

            % If the Channels are valid and selected by user then configure them 
            if(~strcmp(app.DEV1_sensor_CH0EditField.Value, ''))
                addAnalogInputChannel(session,app.NI_DAQ1nameEditField.Value, 0, 'Voltage');
                selectedChannels(1) = true;
            end
            if(~strcmp(app.DEV1_sensor_CH1EditField.Value, ''))
                addAnalogInputChannel(session,app.NI_DAQ1nameEditField.Value, 1, 'Voltage');
                selectedChannels(2) = true;
            end
            if(~strcmp(app.DEV1_sensor_CH2EditField.Value, ''))
                addAnalogInputChannel(session,app.NI_DAQ1nameEditField.Value, 2, 'Voltage');
                selectedChannels(3) = true;
            end
                % If number of channels selected is not all of the
                % available ones ask user for confirmation
            if sum(selectedChannels) ~= 3 && app.number_of_expected_devices == 1
                message = sprintf("Continue With Only %d/3 Channels in Device 1", sum(selectedChannels(1:3)));
                selection = uiconfirm(app.UIFigure,message,"Warning", Icon="warning");
                if strcmp(selection,'Cancel')
                    return
                end
            end

            % If the number of devices selected detected is 2 do the same check for
            % those channels and configure them
            if (app.number_of_expected_devices == 2)
                
                % If the Channels are valid and selected by user then configure them
                if(~strcmp(app.DEV2_sensor_CH0EditField.Value, ''))
                    addAnalogInputChannel(session,app.NI_DAQ2nameEditField.Value, 1, 'Voltage');
                    selectedChannels(4) = true;
                end
                if(~strcmp(app.DEV2_sensor_CH1EditField.Value, ''))
                    addAnalogInputChannel(session,app.NI_DAQ2nameEditField.Value, 2, 'Voltage');
                    selectedChannels(5) = true;
                end
                if(~strcmp(app.DEV2_sensor_CH2EditField.Value, ''))
                    addAnalogInputChannel(session,app.NI_DAQ2nameEditField.Value, 3, 'Voltage');
                    selectedChannels(6) = true;
                end
                % If number of channels selected is not all of the
                % available ones ask user for confirmation
                if sum(selectedChannels(1:3)) ~= 3 || sum(selectedChannels(4:6)) ~= 3
                    message = sprintf("Continue With Only %d/3 Channels in Device 1\nContinue With Only %d/3 Channels in Device 2", sum(selectedChannels(1:3)), sum(selectedChannels(4:6)));
                    selection = uiconfirm(app.UIFigure,message,"Warning", Icon="warning");
                    if strcmp(selection,'Cancel')
                        return
                    end
                end
            end
            
            % After the user confirmed all settings deactivate the launch button
            app.LaunchButton.Enable = "off";
            
            % Making a list of the inputs by the user (To avoid the user
            % changing these values during the test)
            sensor_numerical_inputs = [app.DEV1_sensor_CH0_gainSpinner.Value,app.DEV1_sensor_CH1_gainSpinner.Value,app.DEV1_sensor_CH2_gainSpinner.Value, app.DEV2_sensor_CH0_gainSpinner.Value, app.DEV2_sensor_CH1_gainSpinner.Value, app.DEV1_sensor_CH2_gainSpinner.Value];
            expected_devices = app.number_of_expected_devices;
            data_file_location = app.LocationofdatafileEditField.Value;
            spec_freq = [app.Spec_freqEditField.Value,app.EditField_2.Value,app.EditField.Value];
            spec_SRS = [app.Spec_SRSEditField.Value,app.EditField_16.Value,app.EditField_15.Value];

            % Defining the the sensor gains
            sensor_gains = 1./(sensor_numerical_inputs.*1e-6);

            % Start timer
            app.WaitLamp.Color = 'yellow';
            app.LaunchButton.Text = 'Wait';
            pause(2)

            app.WaitLamp.Color = 'red';
            app.LaunchButton.Text = 'Drop';

            % Configuring the dataloggers and defining the raw data
            [raw_data,time] = session.startForeground;

            % Initialize full data matrix with NaNs
            numSamples = size(raw_data, 1);
            all_data = NaN(numSamples, 6); 
            
            % Fill selected columns
            rawCol = 1;
            for channel = 1:6
                if selectedChannels(channel)
                    all_data(:, channel) = raw_data(:, rawCol);
                    rawCol = rawCol + 1;
                end
            end

            pause(1)

            app.WaitLamp.Color = 'green';
            app.LaunchButton.Text = 'Processing';

            % Save raw data ===============================================

            clocknow = clock;
            timeid = datestr(clocknow,'yyyymmddHHMMSS');
            save([filename,timeid,'.mat'],'all_data','time')
            
            sample_rate = session.Rate/1000;
            
            % Edge detection ==============================================

            impact_points = Edge_detec(app,all_data,selectedChannels,sensor_numerical_inputs);
            impact_points_dev1 = impact_points(1:3);
            impact_points_dev2 = impact_points(4:6);
            
            % Median of non-zero channels for each device
            median_dev1 = Non_zero_median(app,impact_points_dev1);
            median_dev2 = Non_zero_median(app,impact_points_dev2);
            
            % Debugging
            app.LaunchButton.Text = num2str(median_dev1);
            pause(1)
            app.LaunchButton.Text = num2str(median_dev2);
            pause(1)

            % If all of the channels do not detect any shock report error
            if isequal(impact_points,zeros(1, 6))
                
                uialert(app.UIFigure,"No Significant Shock Detected","Measurement Error");
                app.LaunchButton.Enable = "on";
                app.LaunchButton.Text = 'Launch';
                return

            end

            % Start of Plotting ===========================================
            
            % Defines how long (In data points) the shock will be analised for
            shock_length = 10000;
            
            % Loops through all the expected devices
            for device_number=1:expected_devices

                % Loops through each of the channels of the device
                for channel=1:3

                    gain = sensor_gains(channel+((device_number-1)*3));
                    
                    % If the loop is going through dev 1/2 use median of
                    % selected channels for such device to define the start
                    % of data collection
                    if(device_number==1)
                        data = cat(2,time([median_dev1-100:shock_length+median_dev1]),all_data([median_dev1-100:shock_length+median_dev1],channel+((device_number-1)*3))*gain);
                    else
                        data = cat(2,time([median_dev2-100:shock_length+median_dev2]),all_data([median_dev2-100:shock_length+median_dev2],channel+((device_number-1)*3))*gain);
                    end
                    
                    data(:,2) = detrend(data(:,2),'constant');
                    velocity=cumtrapz(data(:,1),data(:,2))*9.8;

                    % Determine which axes to use
                    accel_axes = app.(sprintf('UIAxes_%d', (device_number-1)*6 + (channel-1)*2 + 1));
                    vel_axes   = app.(sprintf('UIAxes_%d', (device_number-1)*6 + (channel-1)*2 + 2));
                    
                    % Plot acceleration
                    plot(accel_axes, data(:,1), data(:,2))
                    xlim(accel_axes, [data(1,1) data(end,1)])
                    ylabel(accel_axes, 'Acceleration (g)')
                    xlabel(accel_axes, 'Seconds')
                    title(accel_axes, 'acceleration')
                    
                    % Plot velocity
                    plot(vel_axes, data(:,1), velocity, 'b')
                    xlim(vel_axes, [data(1,1) data(end,1)])
                    xlabel(vel_axes, 'Seconds')
                    title(vel_axes, 'velocity(m/s)')
                    
                    [x_pos,x_neg,fn] = SRS_calc(app,data);
                    CH_neg(:,channel+((device_number-1)*3)) = x_neg;
                    CH_pos(:,channel+((device_number-1)*3)) = x_pos;
                    CH_fn(:,channel+((device_number-1)*3)) = fn;        
                end
            end
            
            save([filename,timeid,'_SRS_data.mat'],'CH_neg','CH_pos','CH_fn')
            
            if(expected_devices == 2)

            % Generate average curves of the +ve and -ve sensors
            CH_f = CH_fn(:,1);
            CH_X_avg = (CH_pos(:,1)+CH_pos(:,4)-CH_neg(:,1)-CH_neg(:,4))/4;
            CH_Y_avg = (CH_pos(:,2)+CH_pos(:,5)-CH_neg(:,2)-CH_neg(:,5))/4;
            CH_Z_avg = (CH_pos(:,3)+CH_pos(:,6)-CH_neg(:,3)-CH_neg(:,6))/4;
                
                cla(app.MainGraph);
                hold(app.MainGraph, 'on');
                loglog(app.MainGraph,CH_f,CH_X_avg,'LineWidth',2,'DisplayName','X axis',...
                    'Color',[0.0745098039215686 0.623529411764706 1]);
                loglog(app.MainGraph,CH_f,CH_Y_avg,'LineWidth',2,'DisplayName','Y axis',...
                    'Color',[1 0.411764705882353 0.16078431372549]);
                loglog(app.MainGraph,CH_f,CH_Z_avg,'LineWidth',2,'DisplayName','Z axis',...
                    'Color',[0.392156862745098 0.831372549019608 0.0745098039215686]);
  
                
                % Plot the spec SRS + tolerance bands
                
                loglog(app.MainGraph,spec_freq,spec_SRS,'k','linewidth',1,'DisplayName','Target');
                loglog(app.MainGraph,spec_freq,spec_SRS/sqrt(2),'-.k','linewidth',1,'DisplayName','-3 dB','LineStyle','-.');
                loglog(app.MainGraph,spec_freq,spec_SRS*2,'--k','linewidth',1,'DisplayName','+6 dB','LineStyle','--');
                
                % Create zlabel
                zlabel(app.MainGraph,'ZLabel');
                
                % Create ylabel
                ylabel(app.MainGraph,{'Peak Acceleration (g)'},'HorizontalAlignment','center');
                
                % Create xlabel
                xlabel(app.MainGraph,{'Frequency (Hz)'},'HorizontalAlignment','center');
                
                % Create title
                title(app.MainGraph,'Acceleration Shock Response Spectrum Q=10',...
                    'HorizontalAlignment','center',...
                    'FontWeight','bold');
                hold(app.MainGraph, 'off');
            end

            % At the end 
            app.LaunchButton.Enable = "on";
            app.LaunchButton.Text = 'Launch';


        end

        % Value changed function: UseDevice2CheckBox
        function Use_Device2_callback(app, event)

            use_device_2 = app.UseDevice2CheckBox.Value;

            % Enable/Disable Device 2 based on Checkbox ===================

            % Extracting the state of the toggle
            app.NI_DAQ2nameEditField.Enable = "off";
            
            % By default all the other buttons are disabled
            app.DEV2_sensor_CH0EditField.Enable = "off";
            app.DEV2_sensor_CH0_gainSpinner.Enable = "off";

            app.DEV2_sensor_CH1EditField.Enable = "off";
            app.DEV2_sensor_CH1_gainSpinner.Enable = "off";

            app.DEV2_sensor_CH2EditField.Enable = "off";
            app.DEV2_sensor_CH2_gainSpinner.Enable = "off";
            app.NI_DAQ2nameEditField.Value = '';

            app.number_of_expected_devices = 1;

            if use_device_2
                
                app.LaunchButton.Enable = "off";
                app.Device2_ready = false;
                app.number_of_expected_devices = 2;
                app.NI_DAQ2nameEditField.Enable = "on";

            end

            if app.number_of_expected_devices == 2
                if app.Device1_ready && app.Device2_ready 
                    app.LaunchButton.Enable = "on";
                end
            else
                if app.Device1_ready 
                app.LaunchButton.Enable = "on";
                end
            end

        end

        % Value changed function: NI_DAQ1nameEditField
        function Device1_callback(app, event)

            % Device 1 Configurations =====================================

            % extracting the name of device 1
            NI_DAQ1name = app.NI_DAQ1nameEditField.Value;
            
            % By default all the other buttons are disabled
            app.DEV1_sensor_CH0EditField.Enable = "off";
            app.DEV1_sensor_CH0_gainSpinner.Enable = "off";

            app.DEV1_sensor_CH1EditField.Enable = "off";
            app.DEV1_sensor_CH1_gainSpinner.Enable = "off";

            app.DEV1_sensor_CH2EditField.Enable = "off";
            app.DEV1_sensor_CH2_gainSpinner.Enable = "off";

            % Checks if user imput has spaces
            if contains(NI_DAQ1name,' ')
                uialert(app.UIFigure,"Please Do Not Use Spaces.","Input Error");
                app.NI_DAQ1nameEditField.Value = '';
                app.Device1_ready = false;
                app.LaunchButton.Enable = "off";
            else
                % Checks if user imput matches detected ID
                if NI_DAQ1name ~= app.device_list.DeviceID(1)
                    uialert(app.UIFigure,"Please check input NI_DAQ1name is correct.","Input Error");
                    app.NI_DAQ1nameEditField.Value = '';
                    app.Device1_ready = false;
                    app.LaunchButton.Enable = "off";
                else
                    % If it passes the tests the device1 is set to ready
                    app.Device1_ready = true;

                    % Enables all the other buttons
                    app.DEV1_sensor_CH0EditField.Enable = "on";
                    app.DEV1_sensor_CH0_gainSpinner.Enable = "on";

                    app.DEV1_sensor_CH1EditField.Enable = "on";
                    app.DEV1_sensor_CH1_gainSpinner.Enable = "on";
                    
                    app.DEV1_sensor_CH2EditField.Enable = "on";
                    app.DEV1_sensor_CH2_gainSpinner.Enable = "on";
                    
                    % Updating the launch button if all the expected
                    % devices are ready
                    if app.number_of_expected_devices == 2
                        if app.Device1_ready && app.Device2_ready 
                            app.LaunchButton.Enable = "on";
                        end
                    else
                        if app.Device1_ready 
                            app.LaunchButton.Enable = "on";
                        end
                    end
                end
            end
            
        end

        % Value changed function: NI_DAQ2nameEditField
        function Device2_callback(app, event)
               
            % Device 2 Configurations =====================================
            
            % Extracting the name of device 2
            NI_DAQ2name = app.NI_DAQ2nameEditField.Value;

            % By default all the other buttons are disabled
            app.DEV2_sensor_CH0EditField.Enable = "off";
            app.DEV2_sensor_CH0_gainSpinner.Enable = "off";

            app.DEV2_sensor_CH1EditField.Enable = "off";
            app.DEV2_sensor_CH1_gainSpinner.Enable = "off";

            app.DEV2_sensor_CH2EditField.Enable = "off";
            app.DEV2_sensor_CH2_gainSpinner.Enable = "off";

            % Checks if user imput has spaces
            if contains(NI_DAQ2name,' ')
                uialert(app.UIFigure,"Please Do Not Use Spaces.","Input Error");
                app.NI_DAQ2nameEditField.Value = '';
                app.Device2_ready = false;
                app.LaunchButton.Enable = "off";
            else
                % Checks if user imput matches detected ID
                if NI_DAQ2name ~= app.device_list.DeviceID(2)
                    uialert(app.UIFigure,"Please Check Input NI_DAQ2name is Correct.","Input Error");
                    app.NI_DAQ2nameEditField.Value = '';
                    app.Device2_ready = false;
                    app.LaunchButton.Enable = "off";
                else
                    % If it passes the tests the device2 is set to ready
                    app.Device2_ready = true;

                    % Enables all the other buttons
                    app.DEV2_sensor_CH0EditField.Enable = "on";
                    app.DEV2_sensor_CH0_gainSpinner.Enable = "on";

                    app.DEV2_sensor_CH1EditField.Enable = "on";
                    app.DEV2_sensor_CH1_gainSpinner.Enable = "on";

                    app.DEV2_sensor_CH2EditField.Enable = "on";
                    app.DEV2_sensor_CH2_gainSpinner.Enable = "on";
                    
                    % Updating the launch button if all the expected
                    % devices are ready
                    if app.Device1_ready && app.Device2_ready
                        app.LaunchButton.Enable = "on";
                    end  
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainOperationsTab = uitab(app.TabGroup);
            app.MainOperationsTab.Title = 'Main Operations';
            app.MainGraph = uiaxes(app.MainOperationsTab);
            title(app.MainGraph, 'Title')
            xlabel(app.MainGraph, 'X')
            ylabel(app.MainGraph, 'Y')
            zlabel(app.MainGraph, 'Z')
            app.UseDevice2CheckBox = uicheckbox(app.MainOperationsTab);
            app.UseDevice2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Use_Device2_callback, true);
            app.UseDevice2CheckBox.Text = '  Use Device 2';
            app.UseDevice2CheckBox.Value = true;
            app.Device1Label = uilabel(app.MainOperationsTab);
            app.Device1Label.Text = '------------ Device 1 ----------';
            app.Device2Label = uilabel(app.MainOperationsTab);
            app.Device2Label.Text = '----------- Device 2 -----------';
            app.ConfigurationsLabel = uilabel(app.MainOperationsTab);
            app.ConfigurationsLabel.Text = '----------------------------------- Configurations ----------------------------------------';
            app.kHLabel = uilabel(app.MainOperationsTab);
            app.kHLabel.Text = '(kH)';
            app.sLabel = uilabel(app.MainOperationsTab);
            app.sLabel.Text = '(s)';
            app.PlotDataCheckBox = uicheckbox(app.MainOperationsTab);
            app.PlotDataCheckBox.Text = '  Plot Data';
            app.PlotDataCheckBox.Value = true;
            app.DescriptionforlogfileCheckBox = uicheckbox(app.MainOperationsTab);
            app.DescriptionforlogfileCheckBox.Text = '  Description for log file';
            app.DescriptionforlogfileCheckBox.Value = true;
            app.MainPlotLabel = uilabel(app.MainOperationsTab);
            app.MainPlotLabel.Text = '---------------------- Main Plot ---------------------';
            app.StatusLabel = uilabel(app.MainOperationsTab);
            app.StatusLabel.Text = '-------------- Status ----------------';
            app.WaitLamp = uilamp(app.MainOperationsTab);
            app.LaunchButton = uibutton(app.MainOperationsTab, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @Launch_callback, true);
            app.LaunchButton.Enable = 'off';
            app.LaunchButton.Text = 'Launch';
            app.EditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField.Limits = [0 100000];
            app.EditField.ValueDisplayFormat = '%11.5g';
            app.EditField.Value = 10000;
            app.EditField_2 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_2.Limits = [0 10000];
            app.EditField_2.Value = 1000;
            app.EditField_3 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_3.Limits = [0 100000];
            app.EditField_3.ValueDisplayFormat = '%11.5g';
            app.EditField_3.Value = 10000;
            app.EditField_4 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_4.Limits = [0 10000];
            app.EditField_4.Value = 1000;
            app.EditField_5 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_5.Limits = [0 100000];
            app.EditField_5.ValueDisplayFormat = '%11.5g';
            app.EditField_5.Value = 10000;
            app.EditField_6 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_6.Limits = [0 10000];
            app.EditField_6.Value = 1000;
            app.EditField_7 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.ValueDisplayFormat = '%11.5g';
            app.EditField_7.Value = 10000;
            app.EditField_8 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.Value = 1000;
            app.EditField_9 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.Value = 350;
            app.EditField_10 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_10.Limits = [0 Inf];
            app.EditField_10.Value = 350;
            app.EditField_11 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.Value = 350;
            app.EditField_12 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_12.Limits = [0 Inf];
            app.EditField_12.Value = 350;
            app.EditField_13 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_13.Limits = [0 Inf];
            app.EditField_13.Value = 350;
            app.EditField_14 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_14.Limits = [0 Inf];
            app.EditField_14.Value = 350;
            app.EditField_15 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_15.Limits = [0 Inf];
            app.EditField_15.Value = 350;
            app.EditField_16 = uieditfield(app.MainOperationsTab, 'numeric');
            app.EditField_16.Limits = [0 Inf];
            app.EditField_16.Value = 350;
            app.NI_DAQ1nameEditFieldLabel = uilabel(app.MainOperationsTab);
            app.NI_DAQ1nameEditFieldLabel.Text = 'NI_DAQ1name';
            app.NI_DAQ1nameEditField = uieditfield(app.MainOperationsTab, 'text');
            app.NI_DAQ1nameEditField.ValueChangedFcn = createCallbackFcn(app, @Device1_callback, true);
            app.DEV1_sensor_CH0EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH0EditFieldLabel.Text = 'DEV1_sensor_CH0';
            app.DEV1_sensor_CH0EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV1_sensor_CH0EditField.Enable = 'off';
            app.DEV1_sensor_CH0EditField.Value = '12847X';
            app.DEV1_sensor_CH1EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH1EditFieldLabel.Text = 'DEV1_sensor_CH1';
            app.DEV1_sensor_CH1EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV1_sensor_CH1EditField.Enable = 'off';
            app.DEV1_sensor_CH1EditField.Value = '16135Y';
            app.DEV1_sensor_CH2EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH2EditFieldLabel.Text = 'DEV1_sensor_CH2';
            app.DEV1_sensor_CH2EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV1_sensor_CH2EditField.Enable = 'off';
            app.DEV1_sensor_CH2EditField.Value = '16132Z';
            app.DEV1_sensor_CH0_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH0_gainSpinnerLabel.Text = 'DEV1_sensor_CH0_gain';
            app.DEV1_sensor_CH0_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV1_sensor_CH0_gainSpinner.Limits = [1 Inf];
            app.DEV1_sensor_CH0_gainSpinner.Enable = 'off';
            app.DEV1_sensor_CH0_gainSpinner.Value = 942;
            app.DEV1_sensor_CH1_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH1_gainSpinnerLabel.Text = 'DEV1_sensor_CH1_gain';
            app.DEV1_sensor_CH1_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV1_sensor_CH1_gainSpinner.Limits = [1 Inf];
            app.DEV1_sensor_CH1_gainSpinner.Enable = 'off';
            app.DEV1_sensor_CH1_gainSpinner.Value = 1059;
            app.DEV1_sensor_CH2_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV1_sensor_CH2_gainSpinnerLabel.Text = 'DEV1_sensor_CH2_gain';
            app.DEV1_sensor_CH2_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV1_sensor_CH2_gainSpinner.Limits = [1 Inf];
            app.DEV1_sensor_CH2_gainSpinner.Enable = 'off';
            app.DEV1_sensor_CH2_gainSpinner.Value = 981;
            app.NI_DAQ2nameEditFieldLabel = uilabel(app.MainOperationsTab);
            app.NI_DAQ2nameEditFieldLabel.Text = 'NI_DAQ2name';
            app.NI_DAQ2nameEditField = uieditfield(app.MainOperationsTab, 'text');
            app.NI_DAQ2nameEditField.ValueChangedFcn = createCallbackFcn(app, @Device2_callback, true);
            app.DEV2_sensor_CH0EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH0EditFieldLabel.Text = 'DEV2_sensor_CH0';
            app.DEV2_sensor_CH0EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV2_sensor_CH0EditField.Enable = 'off';
            app.DEV2_sensor_CH0EditField.Value = '16131X';
            app.DEV2_sensor_CH1EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH1EditFieldLabel.Text = 'DEV2_sensor_CH1';
            app.DEV2_sensor_CH1EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV2_sensor_CH1EditField.Enable = 'off';
            app.DEV2_sensor_CH1EditField.Value = '11057Y';
            app.DEV2_sensor_CH2EditFieldLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH2EditFieldLabel.Text = 'DEV2_sensor_CH2';
            app.DEV2_sensor_CH2EditField = uieditfield(app.MainOperationsTab, 'text');
            app.DEV2_sensor_CH2EditField.Enable = 'off';
            app.DEV2_sensor_CH2EditField.Value = '16133Z';
            app.DEV2_sensor_CH0_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH0_gainSpinnerLabel.Text = 'DEV2_sensor_CH0_gain';
            app.DEV2_sensor_CH0_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV2_sensor_CH0_gainSpinner.Limits = [1 Inf];
            app.DEV2_sensor_CH0_gainSpinner.Enable = 'off';
            app.DEV2_sensor_CH0_gainSpinner.Value = 982;
            app.DEV2_sensor_CH1_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH1_gainSpinnerLabel.Text = 'DEV2_sensor_CH1_gain';
            app.DEV2_sensor_CH1_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV2_sensor_CH1_gainSpinner.Limits = [1 Inf];
            app.DEV2_sensor_CH1_gainSpinner.Enable = 'off';
            app.DEV2_sensor_CH1_gainSpinner.Value = 998;
            app.DEV2_sensor_CH2_gainSpinnerLabel = uilabel(app.MainOperationsTab);
            app.DEV2_sensor_CH2_gainSpinnerLabel.Text = 'DEV2_sensor_CH2_gain';
            app.DEV2_sensor_CH2_gainSpinner = uispinner(app.MainOperationsTab);
            app.DEV2_sensor_CH2_gainSpinner.Limits = [1 Inf];
            app.DEV2_sensor_CH2_gainSpinner.Enable = 'off';
            app.DEV2_sensor_CH2_gainSpinner.Value = 971;
            app.sample_rateSpinnerLabel = uilabel(app.MainOperationsTab);
            app.sample_rateSpinnerLabel.Text = 'sample_rate';
            app.sample_rateSpinner = uispinner(app.MainOperationsTab);
            app.sample_rateSpinner.Limits = [1 999];
            app.sample_rateSpinner.Value = 80;
            app.sample_durationSpinnerLabel = uilabel(app.MainOperationsTab);
            app.sample_durationSpinnerLabel.Text = 'sample_duration';
            app.sample_durationSpinner = uispinner(app.MainOperationsTab);
            app.sample_durationSpinner.Limits = [1 9];
            app.sample_durationSpinner.Value = 5;
            app.Spec_freqEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freqEditFieldLabel.Text = 'Spec_freq';
            app.Spec_freqEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freqEditField.Limits = [0 1000];
            app.Spec_freqEditField.Value = 100;
            app.Spec_freq_xEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_xEditFieldLabel.Text = 'Spec_freq_x';
            app.Spec_freq_xEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freq_xEditField.Limits = [0 1000];
            app.Spec_freq_xEditField.Value = 100;
            app.Spec_freq_yEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_yEditFieldLabel.Text = 'Spec_freq_y';
            app.Spec_freq_yEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freq_yEditField.Limits = [0 1000];
            app.Spec_freq_yEditField.Value = 100;
            app.Spec_freq_zEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_zEditFieldLabel.Text = 'Spec_freq_z';
            app.Spec_freq_zEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freq_zEditField.Limits = [0 Inf];
            app.Spec_freq_zEditField.Value = 100;
            app.Spec_SRS_xEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRS_xEditFieldLabel.Text = 'Spec_SRS_x';
            app.Spec_SRS_xEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_SRS_xEditField.Limits = [0 Inf];
            app.Spec_SRS_xEditField.Value = 10;
            app.Spec_freq_yEditField_2Label = uilabel(app.MainOperationsTab);
            app.Spec_freq_yEditField_2Label.Text = 'Spec_freq_y';
            app.Spec_freq_yEditField_2 = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freq_yEditField_2.Limits = [0 Inf];
            app.Spec_freq_yEditField_2.Value = 10;
            app.Spec_freq_zEditField_2Label = uilabel(app.MainOperationsTab);
            app.Spec_freq_zEditField_2Label.Text = 'Spec_freq_z';
            app.Spec_freq_zEditField_2 = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_freq_zEditField_2.Limits = [0 Inf];
            app.Spec_freq_zEditField_2.Value = 10;
            app.Spec_SRSEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRSEditFieldLabel.Text = 'Spec_SRS';
            app.Spec_SRSEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.Spec_SRSEditField.Limits = [0 Inf];
            app.Spec_SRSEditField.Value = 10;
            app.LocationofdatafileEditFieldLabel = uilabel(app.MainOperationsTab);
            app.LocationofdatafileEditFieldLabel.Text = 'Location of data file:';
            app.LocationofdatafileEditField = uieditfield(app.MainOperationsTab, 'text');
            app.IndividualGraphsTab = uitab(app.TabGroup);
            app.IndividualGraphsTab.Title = 'Individual Graphs';
            app.RawDataGraphsTab = uitab(app.TabGroup);
            app.RawDataGraphsTab.Title = 'Raw Data Graphs';
            app.UIAxes_1 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_12, 'Title')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.Device1Label_2 = uilabel(app.RawDataGraphsTab);
            app.Device1Label_2.Text = '----------------------------- Device 1 -------------------------';
            app.Device2Label_2 = uilabel(app.RawDataGraphsTab);
            app.Device2Label_2.Text = '----------------------------- Device 2 --------------------------';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shock_Test_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end