classdef Shock_Test_App_Unfinished_week8_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainOperationsTab            matlab.ui.container.Tab
        ProjectLabel                 matlab.ui.control.Label
        SelectProject                matlab.ui.control.Button
        NewProject                   matlab.ui.control.Button
        LogFileDescriptionTextArea   matlab.ui.control.TextArea
        LogFileDescriptionLabel      matlab.ui.control.Label
        DataFileConfigurationsLabel  matlab.ui.control.Label
        Z_Axis_Label                 matlab.ui.control.Label
        Y_Axis_Label                 matlab.ui.control.Label
        X_Axis_Label                 matlab.ui.control.Label
        PlotZAxisCheckBox            matlab.ui.control.CheckBox
        PlotYAxisCheckBox            matlab.ui.control.CheckBox
        PlotXAxisCheckBox            matlab.ui.control.CheckBox
        MainPlotAxesLabel            matlab.ui.control.Label
        StartLaunchSequenceButton    matlab.ui.control.Button
        WaitLamp                     matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        MainPlotLabel                matlab.ui.control.Label
        MainGraph                    matlab.ui.control.UIAxes
        ConfigurationsTab            matlab.ui.container.Tab
        gLabel_3                     matlab.ui.control.Label
        gLabel_2                     matlab.ui.control.Label
        gLabel                       matlab.ui.control.Label
        HzLabel_3                    matlab.ui.control.Label
        HzLabel_2                    matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        NewConfigurationfileButton   matlab.ui.control.Button
        ConfigLabel                  matlab.ui.control.Label
        AddNewChannelButton          matlab.ui.control.Button
        RemoveDeviceButton           matlab.ui.control.Button
        AddNewDeviceButton           matlab.ui.control.Button
        SaveConfigurationfileButton  matlab.ui.control.Button
        DevicesLabel                 matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Spec_SRS_zEditField          matlab.ui.control.EditField
        Spec_SRS_zEditFieldLabel     matlab.ui.control.Label
        Spec_SRS_yEditField          matlab.ui.control.EditField
        Spec_SRS_yEditFieldLabel     matlab.ui.control.Label
        Spec_SRS_xEditField          matlab.ui.control.EditField
        Spec_SRS_xEditFieldLabel     matlab.ui.control.Label
        Spec_freq_zEditField         matlab.ui.control.EditField
        Spec_freq_zEditFieldLabel    matlab.ui.control.Label
        Spec_freq_yEditField         matlab.ui.control.EditField
        Spec_freq_yEditFieldLabel    matlab.ui.control.Label
        Spec_freq_xEditField         matlab.ui.control.EditField
        Spec_freq_xEditFieldLabel    matlab.ui.control.Label
        sample_durationSpinner       matlab.ui.control.Spinner
        sample_durationSpinnerLabel  matlab.ui.control.Label
        sample_rateSpinner           matlab.ui.control.Spinner
        sample_rateSpinnerLabel      matlab.ui.control.Label
        LoadConfigurationfileButton  matlab.ui.control.Button
        sLabel                       matlab.ui.control.Label
        ConfigurationsLabel          matlab.ui.control.Label
        kHzLabel                     matlab.ui.control.Label
        RawPlotsTab                  matlab.ui.container.Tab
        Tree                         matlab.ui.container.Tree
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % GLOBALLY ACESSIBLE VARIABLES ====================================

        % Plotting variables
        list_of_channels = [];
        all_data
        sensor_gains
        time
        median_dev_impact_points
        shock_length
        CH_pos
        CH_neg
        CH_fn
        spec_freq
        spec_SRS
        spec_freq_x
        spec_SRS_x
        spec_freq_y
        spec_SRS_y
        spec_freq_z
        spec_SRS_z
        channel_axis
        main_plots = cell(1,3);

        % Saving Data
        config_library_location = 'C:\Users\kebl8311\Desktop\ConfigLibrary';
        project_library_location = 'C:\Users\kebl8311\Desktop\ProjectLibrary';
        sensor_library_location = 'C:\Users\kebl8311\Desktop\SensorLibrary';
        current_project_filename = '';
        current_config_filename = '';
        timeid
        
        % Error handling
        previous_inputs = struct( ...
        'freq_x', '', ...
        'freq_y', '', ...
        'freq_z', '', ...
        'SRS_x', '', ...
        'SRS_y', '', ...
        'SRS_z', '', ...
        'Channels', '' ...
        );
        
        % Table
        columnHeaders;
        columnFormats;
        columnEditable = false();
        tableData;

    end
    
    methods (Access = private)
        
        function impact_points = Edge_detec(app,all_data,selected_channels,sensor_numeric_inputs)
            % Edge detection of the raw signal to specify where to analyse the data
            % * all_data is a cell array containing the data for each
            % channel (for example: all_data{2}{3} is the data for the 3rd
            % channel of the 2nd device)
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * sensor_numeric_inputs is a cell array containing P lists
            % containing the numerical adjustments for each channel (for
            % example: sensor_numeric_inputs{2}(3) is the adjustment value
            % for the 3rd channel in the 2nd device)
            % * impact_points is a cell array that has N lists of the 
            % outputs of all the impact points for each channel in the  
            % device, where N is the number of devices. If channel is not 
            % selected then it will default to 0 (for example:
            % impact_points{2}(3) is the detected impact time for the 3rd
            % channel of the 2nd device)
            
            % Number of devices
            number_of_devices = length(selected_channels);
            
            % Preallocating the impact_points cell array
            impact_points = cell(1, number_of_devices);

            % Looping over all channels
            for device = 1:number_of_devices
                
                % Number of channels in  each device
                number_of_channels = length(selected_channels{device});
                
                % Preallocating the impact point list
                impact_points{device} = zeros(1,number_of_channels);

                for channel = 1:number_of_channels
                
                    % Checking if channel is selected by the user
                    if selected_channels{device}(channel)
                        
                        % Finding the difference between sucssecive data points
                        % of each channel
                        test = diff(all_data{device}{channel}*sensor_numeric_inputs{device}(channel));
                        
                        % Looping over the whole data
                        for i_loop=1:length(test)
    
                            if(abs(test(i_loop)) > 10) && i_loop > 100 && abs(all_data{device}{channel}(i_loop)*sensor_numeric_inputs{device}(channel)) > 20

                                impact_points{device}(channel) = i_loop;
                                break

                            end
                        end
                    end
                end
            end

        end
        
        function special_median = Non_zero_median(app,input_cell)
            % Calculates the rownded down median of the non-zero entries
            % * input_array is a cell array made of lists of numbers 
            % * special_median is a cell array with the same number of
            % cells as "input_array" but with the integer median 
            % (rounded down) of the non-zero entries for that list (for
            % example: if input_array = {[1,2,3,0,5],[0,0],[1,1,3]} then
            % special median = {2,0,1})
            
            
            special_median = cell(size(input_cell)); % Preallocate output cell array

            for cells = 1:length(input_cell)

                non_zero_values = input_cell{cells}(input_cell{cells} ~= 0);

                if isempty(non_zero_values)

                    special_median{cells} = 0;

                else

                    special_median{cells} = floor(median(non_zero_values));

                end
            end


        end
        
        function [x_pos,x_neg,fn] = SRS_calc(app, time_series, acc_data)
            % Calculates the maximum positive and negative amplitudes of
            % oscillation from damped SHM objects with different natural 
            % frequencieswhen driven by the shock accelerometer data, this
            % is the shock response spectrum (SRS)
            % ver 2.0 July 3, 2006 by Tom Irvine Email: tomirvine@aol.com
            % * time_series is a 1D array containing the timestamps of the
            % accelerometer data
            % * acc_data is a 1D array containing the data from the
            % accelerometer in temporal order
            % * x_pos is a 1D array containing the maximum positive
            % displacement of each SHM objects 
            % * x_neg is a 1D array containing the maximum negative
            % displacement of each SHM object
            % * fn is a 1D array containing the natural frequencies of the
            % SHM objects
            
            % Gathering inputs
            t=time_series;
            y=acc_data;
            tmx=max(t);
            tmi=min(t);
            n = length(t);
            
            % Calculating the "dt" timestep
            dt=(tmx-tmi)/(n-1);

            % Calcultaing the frequency
            sr=1./dt;
            
            % Starting frequency
            fn(1) = 20;
            if fn(1)>sr/30.
                fn(1)=sr/30.;
            end
           
            % Damping factor
            Q = 10;
            damp=1./(2.*Q);
            
            % Choosing algorithm
            ialgorithm=1;
            
            tmax=(tmx-tmi) + 1./fn(1);
            limit = round( tmax/dt );
            n=limit;
            yy=zeros(1,limit);
            for i=1:length(y)
                    yy(i)=y(i);
            end

            % Looping "forever" until the condition is satisfied
            for j=1:1000

                omega=2.*pi*fn(j);
                omegad=omega*sqrt(1.-(damp^2));
                cosd=cos(omegad*dt);
                sind=sin(omegad*dt);
                domegadt=damp*omega*dt;

                if(ialgorithm==1)
                    a1(j)=2.*exp(-domegadt)*cosd;
                    a2(j)=-exp(-2.*domegadt);
                    b1(j)=2.*domegadt;
                    b2(j)=omega*dt*exp(-domegadt);
                    b2(j)=b2(j)*( (omega/omegad)*(1.-2.*(damp^2))*sind -2.*damp*cosd );
                    b3(j)=0;

                else
	                E=exp(-damp*omega*dt);
		            K=omegad*dt;
		            C=E*cos(K);
		            S=E*sin(K);
		            Sp=S/K;

	                a1(j)=2*C;
		            a2(j)=-E^2;
		            b1(j)=1.-Sp;
		            b2(j)=2.*(Sp-C);
		            b3(j)=E^2-Sp;
                end
                forward=[ b1(j),  b2(j),  b3(j) ];    
                back   =[     1, -a1(j), -a2(j) ];    
    
                resp=filter(forward,back,yy);

                x_pos(j)= max(resp);
                x_neg(j)= min(resp);
  
                jnum=j; 
                if  fn(j) > sr/8.
                    break
                end
                fn(j+1)=fn(1)*(2. ^ (j*(1./12.)));    
            end

        end
        
        function [selected_channels,channel_names,sensor_numerical_inputs,datalogger_positions,channel_axis,device_channel_error] = Config_devices(app, input_table, number_of_channels, session)
            % Configures all the channels that are selected by the user and
            % outputs the list of selected channels
            % * input_table is the table of input channels by the user, each
            % row is structed as follows: device name, channel 1 name,
            % channel 1 adjustment, channel 1 position in datalogger,
            % channel 1 axis and so on (for example: input_table{2,3} is 
            % the gain of channel 1 of the 2nd device)
            % * number_of_channels is a 1D vector containing the expected
            % number of channels in each device in order (for example:
            % number_of_channels(2) is the expected number of channels in
            % device 2)
            % * session is the name of the DAQ session
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * channel_names is a cell array containing P lists
            % containing the names of the channels (for example:
            % channel_names{2}(3) is the name of the 3rd channel
            % in the 2nd device)
            % * sensor_numerical_inputs is a cell array containing P lists
            % containing the adjustments values of the channels (for example:
            % sensor_numerical_inputs{2}(3) is the adjustment value of the 
            % 3rd channel in the 2nd device)
            % * datalogger_positions is a cell array containing P lists
            % containing the datalogger channel position of the channels 
            % (for example: datalogger_positions{2}(3) is the name of the 
            % 3rd channel in the 2nd device)
            % * channel_axis is a cell array containing P lists
            % containing the axis of the channels (for example:
            % channel_names{2}(3) is the axis of the 3rd channel
            % in the 2nd device)

            % Number of devices
            number_of_devices = length(number_of_channels);
            
            % Initialize output
            device_channel_error = false;
            selected_channels = cell(1, number_of_devices);
            channel_names = cell(1, number_of_devices);
            sensor_numerical_inputs = cell(1, number_of_devices);
            datalogger_positions = cell(1, number_of_devices);
            channel_axis = cell(1, number_of_devices);
            
            % Loop over all devices
            for device = 1:number_of_devices

                selected_channels{device} = false(1, number_of_channels(device));
                channel_names{device} = strings(1, number_of_channels(device));
                sensor_numerical_inputs{device} = zeros(1, number_of_channels(device));
                datalogger_positions{device} = zeros(1,number_of_channels(device));
                channel_axis{device} = strings(1,number_of_channels(device));
                device_name = input_table{device,1};

                % Loop over all channels
                for channel = 1:number_of_channels(device)
                    
                    % Getting the name, adjustment and datalogger channel number
                    channel_name = string(input_table{device,4*channel-2});
                    channel_adjustment = input_table{device,4*channel-1};
                    datalogger_channel = input_table{device,4*channel};
                    axis = string(input_table{device,4*channel+1});
                    
                    channel_names{device}(channel) = channel_name;
                    sensor_numerical_inputs{device}(channel) = channel_adjustment;
                    datalogger_positions{device}(channel) = datalogger_channel;
                    channel_axis{device}(channel) = axis;

                    % If the channel name is "selected"
                    if(~strcmp(channel_name, '') && ~strcmp(channel_name, 'NaN'))
                        
                        try

                            addAnalogInputChannel(session,device_name, datalogger_channel, 'Voltage');
                            selected_channels{device}(channel) = true;

                        catch ME

                            uialert(app.UIFigure, ['Error adding channel: ' ME.message], 'Channel Error');
                            device_channel_error = true;
                            return

                        end

                    end
                end
            end

        end
        
        function all_data = Process_raw_data(app,raw_data,selected_channels)
            % * raw_data is a NxM matrix containing the N datapoints of the
            % M activated channels in order (for example: raw_data(:,2) is
            % the column of data measured by the 2nd channel to be activated)
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * all_data is a cell array containing the data for each
            % channel (for example: all_data{2}{3} is the data for the 3rd
            % channel of the 2nd device)

            % Number of devices
            number_of_devices = length(selected_channels);

            data_lenght = size(raw_data,1);

            counter = 1;

            for device = 1:number_of_devices
                
                % Number of channels in  each device
                number_of_channels = length(selected_channels{device});

                for channel = 1:number_of_channels
                    
                    if selected_channels{device}(channel)
                    
                        all_data{device}{channel} = raw_data(:,counter);
                        counter = counter + 1;

                    else
                        
                        all_data{device}{channel} = zeros(data_lenght,1);

                    end
                end
            end

        end
        
        function Save_config_file(app,library_location, configuration_name, sample_rate, sample_duration, spec_freq, spec_SRS, desc_log, number_of_channels, input_table, table_headers, column_format, column_editable, produce_text_file)
            % Saves the configuration file (matlab file) and a txt document
            % with the description of how the configuration file is
            % structured. The configuration file is a cell array containing
            % all user inputs and its own hash code as the last input in
            % the cell array
            % * library_location is a char with the location of the
            % configuration library
            % * configuration_name is a char with the name of the current
            % configuration file
            % * sample_rate is a double with the sample rate of the data
            % * sample_duration is a double with the sample duration of of the data 
            % * spec_freq is a cell array conatining the frequencies used 
            % to plot the SRS boundary lines
            % * spec_freq is a cell array conatining the accelerations used 
            % to plot the SRS boundary lines
            % * number_of_channels in a 1D array containing the expected
            % number of channels for each device
            % * input_table is the table of input channels by the user, each
            % column is structed as follows: device name, channel 1 name,
            % channel 1 adjustment, channel 1 channel in datalogger, and so
            % on (for example: input_table{5,3} is the channel 2 name of
            % the 3rd device)
            % * produce_text_file is a boolean value that controls if the 
            % text file description and if the program is going to ask the
            % user to save file
            
            % Create the configuration file 
            config_file = {desc_log, sample_rate, sample_duration, spec_freq, spec_SRS, number_of_channels, input_table, table_headers, column_format,column_editable,"Hash"};
            
            % Create a hash making an unique identifier for the config file
            % the function "DataHash" is the only outside function since it
            % is a 3rd party function
            new_hash = DataHash(config_file);

            % Adding the hash to the end of the file such that if you
            % delete the config file the hash goes away with it, also
            % making it easier to compare the new hash from other config
            % files
            config_file{end} = new_hash;

            % Error handling

            if isequal(configuration_name,'')
                
                % Ask user for name
                prompt = {'No configuration selected. Please input the name of this new configuration:'};
                dlgtitle = 'No Configuration Selected';
                fieldsize = [1 80];
                definput = {''};
                file_name = inputdlg(prompt, dlgtitle, fieldsize, definput);


                % Check if user cancelled or left the input empty
                if isempty(file_name) || isempty(file_name{1}) || all(isspace(file_name{1}))

                    uialert(app.UIFigure, 'No configuration name provided. File not saved.', 'Warning');
                    return

                end
 
                % Clean and prepare name
                configName = strtrim(file_name{1});
                fileName = [configName, '.mat'];
                
                % Check for uniqueness
                existingFiles = dir(fullfile(library_location, '*.mat'));
                existingNames = {existingFiles.name};
                
                if any(strcmpi(existingNames, fileName))  % Case-insensitive match
                    uialert(app.UIFigure, ['A configuration named "' configName '" already exists. Please choose a different name.'], 'Error', 'Icon', 'error');
                    return;
                end
                
                % If it passes through the tests then update the current
                % file name and display it
                app.current_config_filename = char(file_name);
                app.ConfigLabel.Text = ["Current Config File: ",file_name];

                % Also update the configuration name
                configuration_name = char(file_name);

            end
            
            % Saving config file
            fullPath = fullfile(library_location, configuration_name);
            save(fullPath, 'config_file');

            if ~produce_text_file
             
                uialert(app.UIFigure, 'Configuration file saved.', 'Info', 'Icon', 'success');

            end
            
        end
        
        function Read_config_file(app, selected_file_path)
            % Reads the user-selected configuration file and outputs the
            % configurations to the interface
            % * selected_file_path is a string containing the user-selected
            % file path


            data = load(selected_file_path);
            cell_array = data.config_file;

            app.LogFileDescriptionTextArea.Value = cell_array{1};
 
            app.sample_rateSpinner.Value = cell_array{2}/1000;
            app.sample_durationSpinner.Value =  cell_array{3};
            
            app.Spec_freq_xEditField.Value = strjoin(string(cell_array{4}{1}), ',');
            app.Spec_SRS_xEditField.Value = strjoin(string(cell_array{5}{1}), ',');

            app.Spec_freq_yEditField.Value = strjoin(string(cell_array{4}{2}), ',');
            app.Spec_SRS_yEditField.Value = strjoin(string(cell_array{5}{2}), ',');

            app.Spec_freq_zEditField.Value = strjoin(string(cell_array{4}{3}), ',');
            app.Spec_SRS_zEditField.Value = strjoin(string(cell_array{5}{3}), ',');
            
            app.list_of_channels = cell_array{6};
            app.tableData = cell_array{7};
            app.columnHeaders = cell_array{8};
            app.columnFormats = cell_array{9};
            app.columnEditable = cell_array{10};
            
            % Assigning the table data to the UI table
            app.UITable.Data = app.tableData;
            app.UITable.ColumnName = app.columnHeaders;
            app.UITable.ColumnFormat = app.columnFormats;
            app.UITable.ColumnEditable = app.columnEditable;

            % Filling the previous input structure
            app.previous_inputs = struct( ...
                'freq_x', strjoin(string(cell_array{4}{1}),','), ...
                'freq_y', strjoin(string(cell_array{4}{2}),','), ...
                'freq_z', strjoin(string(cell_array{4}{3}),','), ...
                'SRS_x', strjoin(string(cell_array{5}{1}),','), ...
                'SRS_y', strjoin(string(cell_array{5}{2}),','), ...
                'SRS_z', strjoin(string(cell_array{5}{3}),','), ...
                'Channels', strjoin(string(cell_array{6}),',') ...
            );

            % Displaying to user the name and location of the config file
            
            [~, name, ext] = fileparts(selected_file_path);
            app.ConfigLabel.Text = ["Current Config File: ",name];


        end
             
        function isValid = Validate_input(app, input_name, new_input, textbox_handle)
            % Function that checks if the input to the text field are comma
            % separated positive integers and if they are not it displays a
            % warning and reverts back to the previously valid input
            % * input_name is the tag for the input in the struct
            % * new_input is the string input of the user
            % * textbox_handle is the handle for the texboxx that the user
            % used to input their string
            % * isValid is a boolean variable representing if the input is
            % valid or not

            % Checks if the input is a list of integers or is empty
            isValid = isempty(new_input) || ~isempty(regexp(new_input, '^\s*[1-9]\d*\s*(,\s*[1-9]\d*\s*)*$', 'once'));

            if isValid

                app.previous_inputs.(input_name) = new_input;

            else

                textbox_handle.Value = app.previous_inputs.(input_name);

                error_message = sprintf('Input Error: "%s" \nis not a comma-separated list of positive integers.\n\nValid input example: 10, 600, 4000', new_input);
                uialert(app.UIFigure, error_message, 'Input Error');
            end

        end
        
        function Averages = Plot_main_graph(app,channel_list, pos_response, neg_response, channel_freq, freq_goal, SRS_goal, axis_selected)
            % Plots the main graph and outputs the average response for
            % each axis
            % * channel_list is an array with the number of channels per
            % device
            % * pos_response is a cell array with each channels SRS 
            % positive response to the shock
            % * neg_response is a cell array with each channels SRS 
            % negative response to the shock
            % * channel_freq is a cell array with each channels SRS
            % frequencies
            % * freq_goal is a cell array containing each axis goal SRS 
            % frequencies
            % * SRS_goal is a cell array containing each axis goal SRS
            % amplitudes
            % * axis_selected is a 1D array with a 1 or 0 depeding if the
            % axis was selected to plot by the user or not
            % * Averages is a cell array containing the average SRS
            % response for the X/Y/Z axis

            % Initialize output
            Averages = cell(1,3);

            % Initialize data_matrix for X, Y, Z axes
            data_matrix = cell(1, 3);
            
            % Loop over all devices
            for device = 1:length(channel_list)

                for channel = 1:channel_list(device)

                    axis = app.channel_axis{device}(channel);

                    % Map axis to index 1, 2, or 3
                    idx = find(strcmp(axis, {'X', 'Y', 'Z'}));  
            
                    if ~isempty(idx)

                        data_matrix{idx} = [data_matrix{idx}, ...
                            pos_response{device}{channel}, ...
                           -neg_response{device}{channel}];
                        
                    end

                end

            end

            % Determine which axes to use
            SRS_axes = app.MainGraph;
                                
            % Plot SR
            cla(SRS_axes);
            hold(SRS_axes, 'on');
            
            % Axis labels and settings
            axes_labels = {'X', 'Y', 'Z'};       
            colors_target = {'#831818', '#1B6D18', '#18286D'};
            colors_avg_rgb = {
                [220, 36, 36] / 255;  
                [97, 197, 31] / 255;   
                [33, 83, 206] / 255    
            };
            
            for i = 1:3

                if ~isempty(data_matrix{i})
                
                    avg_response = mean(data_matrix{i},2);
                    Averages{i} = avg_response;
                    max_response = max(data_matrix{i}, [], 2);
                    min_response = min(data_matrix{i}, [], 2);
                    freq_channel = channel_freq{device}{channel};
    
    
                    % Create shaded area between min and max responses
                    x = [freq_channel; flipud(freq_channel)];
                    y = [min_response; flipud(max_response)];
                    hold(SRS_axes, 'on');

                    app.main_plots{i} = [
                        
                        fill(SRS_axes, x, y, colors_avg_rgb{i}, ...
                            'FaceAlpha', 0.2, ... % Low opacity
                            'EdgeColor', 'none', ...
                            'DisplayName', [axes_labels{i} ' Axis Min-Max Range']);
        
                        % Plot average response prominently
                        loglog(SRS_axes, freq_channel, avg_response, ...
                            'Color', colors_avg_rgb{i}, 'LineWidth', 2, ...
                            'DisplayName', [axes_labels{i} ' Axis Average Response']);
                    
                        % Plot min and max with lower opacity and thinner lines
                        loglog(SRS_axes, freq_channel, max_response, ...
                            'Color', colors_avg_rgb{i}, 'LineWidth', 0.5, 'LineStyle', '--', ...
                            'DisplayName', [axes_labels{i} ' Axis Maximum Response']);
                        loglog(SRS_axes, freq_channel, min_response, ...
                            'Color', colors_avg_rgb{i}, 'LineWidth', 0.5, 'LineStyle', '--', ...
                            'DisplayName', [axes_labels{i} ' Axis Minimum Response']);
                    
                        % Plot target and ±dB lines
                        loglog(SRS_axes, freq_goal{i}, SRS_goal{i}, ...
                            'Color', colors_target{i}, 'LineWidth', 1, 'DisplayName', 'Target');
                        loglog(SRS_axes, freq_goal{i}, SRS_goal{i} / sqrt(2), '-.', ...
                            'Color', colors_target{i}, 'LineWidth', 1, 'DisplayName', '-3 dB');
                        loglog(SRS_axes, freq_goal{i}, SRS_goal{i} * 2, '-.', ...
                            'Color', colors_target{i}, 'LineWidth', 1, 'DisplayName', '+6 dB');
                    ];
                    
                    % Not plotting legend of min/max since it is already
                    % obvious
                    app.main_plots{i}(3).Annotation.LegendInformation.IconDisplayStyle = 'off';
                    app.main_plots{i}(4).Annotation.LegendInformation.IconDisplayStyle = 'off';

                    % Set visibility from user input
                    for j = 1:length(app.main_plots{i})
                            app.main_plots{i}(j).Visible = axis_selected(i);
                    end

                end

            end
            
            % Plot configurations
            set(SRS_axes, 'XScale', 'log', 'YScale', 'log')
            ylabel(SRS_axes, 'Peak Acceleration (g)')
            xlabel(SRS_axes, 'Frequency (Hz)')
            xlim(SRS_axes,[100,10000])
            title(SRS_axes, 'Acceleration Shock Response Spectrum Q=10')
            lgd = legend(SRS_axes,'Location', 'best');
            set(lgd, 'FontSize', 7,'NumColumns', 1);

            hold(SRS_axes, 'off');
            
        end
        
        function compliance_percent = Quality_check(app, SRS_measured, frequency_measured, SRS_desired, frequency_desired)
            % Calcultaes the percentage of data points that are above the
            % target SRS response
            % * SRS_measured is a 1D array containing the calculated SRS
            % response for each frequency
            % * frequency_measured is a 1D array containing the requencies
            % of the calculated SRS response
            % * SRS_desired is a 1D array containing the target SRS
            % response for each frequency
            % * frequency_desired is a 1D array containing the requencies
            % of the desired SRS response
            % * compliance_percent is the ratio between data points that
            % satisfy the condition to the total number of datapoints that
            % are in the range of desired frequencies
            
            if ~isempty(SRS_measured)
                
                % Transform to log-log space
                log_freq_desired = log10(frequency_desired);
                log_SRS_desired = log10(SRS_desired);
                log_freq_measured = log10(frequency_measured);
                
                % Interpolate in log-log space
                log_SRS_interp = interp1(log_freq_desired, log_SRS_desired, log_freq_measured, 'linear');
                
                % Convert interpolated values back to linear scale
                SRS_interp = 10.^log_SRS_interp;

                min_freq = min(frequency_desired);
                max_freq = max(frequency_desired);

                lower_bound = SRS_interp;
    
                counter = 0;
                valid_counter = 0;
    
                for index = 1:length(frequency_measured)
    
                    if (frequency_measured(index) >= min_freq) && (frequency_measured(index) <= max_freq)
    
                        counter = counter + 1;
                        
                        if (SRS_measured(index) >= lower_bound(index))
                            
                            valid_counter = valid_counter + 1;
                           
                        end
    
                    end
    
                end
    
                compliance_percent = (valid_counter/counter)*100;

            else

                compliance_percent = 0;

            end
        end
                
        function selected_choice = Single_dropdown_popup(app, options, message, title)
            % Creates a popup with a dropdown menu, an OK button and a
            % cancel button and then gathers the selected item by the user
            % * options is a cell array containing the device names
            % * message is a char containing the message in the pop up
            % * title is a char containing the title of the pop up
            % * selected_choice is the item selected by the user, it 
            % defaults to '' if cancelled


            % Default output
            selected_choice = '';
        
            % Get app figure position
            appPos = app.UIFigure.Position;  % [x y width height]
        
            % Define popup size
            popupWidth = 350;
            popupHeight = 160;
        
            % Calculate centered position
            popupX = appPos(1) + (appPos(3) - popupWidth)/2;
            popupY = appPos(2) + (appPos(4) - popupHeight)/2;
        
            % Create modal popup centered in app
            d = uifigure('Name', title, ...
                         'Position', [popupX popupY popupWidth popupHeight], ...
                         'WindowStyle', 'modal', ...
                         'Color', [0.95 0.95 0.95]);

            % Handle window close (X button)
            d.CloseRequestFcn = @(src, event) onClose(src);
        
            % Instruction label
            uilabel(d, ...
                'Text', message, ...
                'Position', [50 110 250 30], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center');
        
            % Dropdown
            dd = uidropdown(d, ...
                'Items', options, ...
                'Position', [100 80 150 22], ...
                'FontSize', 12);
        
            % OK button
            uibutton(d, 'Text', 'OK', ...
                'Position', [70 30 90 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn,event) setAndResume(d, dd.Value));
        
            % Cancel button
            uibutton(d, 'Text', 'Cancel', ...
                'Position', [190 30 90 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn,event) setAndResume(d, ''));
        
            % Wait for user action
            uiwait(d);
        
            % Get result
            if isvalid(d)
                selected_choice = d.UserData;
                delete(d);
            end
        
            % Helper function to set value and resume
            function setAndResume(fig, value)
                fig.UserData = value;
                uiresume(fig);
            end
        
            % Handle window close
            function onClose(fig)
                fig.UserData = '';  % Treat as cancel
                uiresume(fig);
            end
        
        end

        function [first_choice, second_choice] = Double_dropdown_popup(app, first_options, second_options, message)
            % Creates a popup with two dropdown menus, an OK button and a
            % cancel button and then gathers the selected items by the user
            % * first_options is a cell array containing the first options
            % * second_options is a cell array containing the second options
            % * selected_device and selected_sensor are the items selected 
            % by the user
        
            % Default outputs
            first_choice = '';
            second_choice = '';
        
            % Get app figure position
            appPos = app.UIFigure.Position;  % [x y width height]
        
            % Define popup size
            popupWidth = 350;
            popupHeight = 200;
        
            % Calculate centered position
            popupX = appPos(1) + (appPos(3) - popupWidth)/2;
            popupY = appPos(2) + (appPos(4) - popupHeight)/2;
        
            % Create modal popup centered in app
            d = uifigure('Name', 'Select Device and Sensor', ...
                         'Position', [popupX popupY popupWidth popupHeight], ...
                         'WindowStyle', 'modal', ...
                         'Color', [0.95 0.95 0.95]);
        
            % Handle window close (X button)
            d.CloseRequestFcn = @(src, event) onClose(src);
        
            % Instruction label
            uilabel(d, ...
                'Text', message, ...
                'Position', [50 150 250 30], ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center');
        
            % Device dropdown
            dd_device = uidropdown(d, ...
                'Items', first_options, ...
                'Position', [100 110 150 22], ...
                'FontSize', 12);
        
            % Sensor dropdown
            dd_sensor = uidropdown(d, ...
                'Items', second_options, ...
                'Position', [100 70 150 22], ...
                'FontSize', 12);
        
            % OK button
            uibutton(d, 'Text', 'OK', ...
                'Position', [70 20 90 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn,event) setAndResume(d, dd_device.Value, dd_sensor.Value));
        
            % Cancel button
            uibutton(d, 'Text', 'Cancel', ...
                'Position', [190 20 90 30], ...
                'FontSize', 12, ...
                'ButtonPushedFcn', @(btn,event) setAndResume(d, '', ''));
        
            % Wait for user action
            uiwait(d);
        
            % Get result
            if isvalid(d)
                first_choice = d.UserData.device;
                second_choice = d.UserData.sensor;
                delete(d);
            end
        
            % Helper function to set values and resume
            function setAndResume(fig, device, sensor)
                fig.UserData.device = device;
                fig.UserData.sensor = sensor;
                uiresume(fig);
            end
        
            % Handle window close
            function onClose(fig)
                fig.UserData.device = '';
                fig.UserData.sensor = '';
                uiresume(fig);
            end
        end
 
        function Configure_table(app, list_of_channels)
            % Configures the device table to show user the devices/channels
            % * list_of_channels is a 1D array containing the number of
            % channels connector to each device (for example:
            % list_of_channels(3) is the number of channels connected to
            % device 3)


            if isempty(list_of_channels)

                app.columnHeaders = {};
                app.columnFormats = {};
                app.tableData = {};
                app.columnEditable = false();
            
            else

                max_channels = max(list_of_channels);
    
                app.columnHeaders{1} = 'Dev Name';
                app.columnFormats{1} = 'char';
                app.columnEditable(1) = true;

                app.tableData(:, (4*max_channels+2):end) = [];
                
                for channel = 1:(max_channels)
                    
                    app.columnHeaders{4*channel-2} = sprintf('Ch%d Name', channel);
                    app.columnHeaders{4*channel-1} = sprintf('Ch%d Gain', channel);
                    app.columnHeaders{4*channel} = sprintf('Ch%d Input', channel);
                    app.columnHeaders{4*channel+1} = sprintf('Ch%d Axis', channel);
    
                    app.columnFormats{4*channel-2} = 'char';
                    app.columnFormats{4*channel-1} = 'numeric';
                    app.columnFormats{4*channel} = 'numeric';
                    app.columnFormats{4*channel+1} = {'X', 'Y', 'Z'};

                    app.columnEditable(4*channel-2) = false;
                    app.columnEditable(4*channel-1) = false;
                    app.columnEditable(4*channel) = true;
                    app.columnEditable(4*channel+1) = true;
    
                end
            end
            
            % Set the headers and data
            app.UITable.ColumnName = app.columnHeaders;
            app.UITable.ColumnFormat = app.columnFormats;
            app.UITable.ColumnEditable = app.columnEditable;
            app.UITable.Data = app.tableData;
            
        end
        
        function Write_txt_file(app, save_location, configuration_name,time,percentages,sample_rate, sample_duration, spec_freq, spec_SRS, desc_log, number_of_channels, input_table)
            % Writes and saves a txt file of the configurations used in the
            % run 
            % * save_location is a char containig the file path of the
            % saving location
            % * configuration_name is a char with the name of the current
            % configuration file
            % * time ID in this format: 2025-09-05 20:12:18
            % * sample_rate is a double with the sample rate of the data
            % * sample_duration is a double with the sample duration of of the data 
            % * spec_freq is a cell array conatining the frequencies used 
            % to plot the SRS boundary lines
            % * spec_freq is a cell array conatining the accelerations used 
            % to plot the SRS boundary lines
            % * number_of_channels in a 1D array containing the expected
            % number of channels for each device
            % * input_table is the table of input channels by the user, each
            % column is structed as follows: device name, channel 1 name,
            % channel 1 adjustment, channel 1 channel in datalogger, and so
            % on (for example: input_table{5,3} is the channel 2 name of
            % the 3rd device)


            % Saving config txt file (cosmetic only) ======================
            
            fileID = fopen([save_location,[app.timeid,'_configuration_description.txt']],'w');
                
            % Create separator line
            separator = repmat('=', 1, 60);
                
            % Write top separator
            fprintf(fileID, '%s\n', separator);
                
            % Write centered heading
            heading = 'HEADING';
            padding = floor((length(separator) - length(heading)) / 2);
            line = [repmat(' ', 1, padding), heading];
            fprintf(fileID, '%s\n', line);
                
            % Write bottom separator
            fprintf(fileID, '%s\n', separator);

            fprintf(fileID, 'Date and time: %s\n', time);
                
            username = getenv('USERNAME');
            fprintf(fileID, 'Name of user: %s\n', username);

            fprintf(fileID, 'Name of configuration file: %s\n', configuration_name);

            % Define indentation to align with "Comment:"
            indent = repmat(' ', 1, 9);  % 9 spaces to match length of "Comment: "
                
            % Write first line with label
            fprintf(fileID, 'Comment: %s\n', desc_log{1});

            % Write first line with label
            fprintf(fileID, 'SRS percentage of data points above target (X axis): %.0f%%\n', percentages(1));
            fprintf(fileID, 'SRS percentage of data points above target (Y axis): %.0f%%\n', percentages(2));
            fprintf(fileID, 'SRS percentage of data points above target (Z axis): %.0f%%\n', percentages(3));

            % Write remaining lines with indentation
            for i = 2:length(desc_log)
                fprintf(fileID, '%s%s\n', indent, desc_log{i});
            end
                
            % Create separator line
            separator = repmat('=', 1, 60);
                
            % Write top separator
            fprintf(fileID, '%s\n', separator);
                
            % Write centered heading
            heading = 'CONFIGURATIONS';
            padding = floor((length(separator) - length(heading)) / 2);
            line = [repmat(' ', 1, padding), heading];
            fprintf(fileID, '%s\n', line);
                
            % Write bottom separator
            fprintf(fileID, '%s\n', separator);

            fprintf(fileID, 'Sample Rate (Hz): %d\n', sample_rate);
            fprintf(fileID, 'Sample Duration (s): %d\n', sample_duration);

            fprintf(fileID, '\n');
                
            axis = ['X','Y','Z'];
            for axes = 1:3
                    
                % Convert arrays to string for output
                freq_str = sprintf('%.0f ', spec_freq{axes});  % space-separated values
                srs_str  = sprintf('%.0f ', spec_SRS{axes});
                
                % Write to file
                fprintf(fileID, 'Spec frequency %s (Hz): %s\n', axis(axes), freq_str);
                fprintf(fileID, 'Spec SRS %s (g):        %s\n\n', axis(axes), srs_str);

            end

            % Create separator line
            separator = repmat('=', 1, 60);
                
            % Write top separator
            fprintf(fileID, '%s\n', separator);
                
            % Write centered heading
            heading = 'DEVICES';
            padding = floor((length(separator) - length(heading)) / 2);
            line = [repmat(' ', 1, padding), heading];
            fprintf(fileID, '%s\n', line);
                
            % Write bottom separator
            fprintf(fileID, '%s\n', separator);

            for device = 1:length(number_of_channels)

                fprintf(fileID, 'Device %d (name: %s)\n\n',device, input_table{device,1});

                for channel = 1:number_of_channels(device)

                    fprintf(fileID, '    Channel %d name:       %s \n',channel,input_table{device,4*channel-2});
                    fprintf(fileID, '    Channel %d adjustment: %d \n',channel,input_table{device,4*channel-1});
                    fprintf(fileID, '    Channel %d position:   %d \n',channel,input_table{device,4*channel});
                    fprintf(fileID, '    Channel %d axis:       %s \n\n',channel,input_table{device,4*channel+1});

                end
            end

            % Close File
            fclose(fileID);
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartLaunchSequenceButton
        function Launch_callback(app, event)
            
            % Changing the button
            app.WaitLamp.Color = 'yellow';
            app.StartLaunchSequenceButton.Text = 'Wait';

            % CLEARING AXIS ===============================================
            
            cla(app.UIAxes);
            cla(app.UIAxes_2);

            % FILE CONFIGURATION ==========================================
            
            if isequal(app.current_project_filename, '')

                % Catching error due top not selecting project
                uialert(app.UIFigure, ...
                'No project has been selected or created. Please select an existing project or create a new one before starting gathering data.', ...
                'Session Error');
                app.WaitLamp.Color = 'green';
                app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';
                return
                
            end

            if isequal(app.current_config_filename, '')

                % Catching error due top not selecting project
                uialert(app.UIFigure, ...
                'No configuration file has been selected or created. Please select an existing configuration file or create a new one before starting data collection.', ...
                'Session Error');
                app.WaitLamp.Color = 'green';
                app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';
                return
                
            end

            app.current_project_filename = [app.current_project_filename,'\'];

            % Starts the config for the dataloggers
            session = daq.createSession('ni');

            % CONFIGURING ALL CHANNELS AND EXTRACTING DATA ================

            sample_rate = app.sample_rateSpinner.Value*1000;
            sample_duration = app.sample_durationSpinner.Value;

            session.Rate = sample_rate;
            session.DurationInSeconds = sample_duration;

            app.spec_freq_x = str2num(app.Spec_freq_xEditField.Value);
            app.spec_SRS_x = str2num(app.Spec_SRS_xEditField.Value);
            
            app.spec_freq_y = str2num(app.Spec_freq_yEditField.Value);
            app.spec_SRS_y = str2num(app.Spec_SRS_yEditField.Value);

            app.spec_freq_z = str2num(app.Spec_freq_zEditField.Value);
            app.spec_SRS_z = str2num(app.Spec_SRS_zEditField.Value);

            app.spec_freq = {app.spec_freq_x,app.spec_freq_y,app.spec_freq_z};
            app.spec_SRS = {app.spec_SRS_x,app.spec_SRS_y,app.spec_SRS_z};

            desc_log = app.LogFileDescriptionTextArea.Value;
            
            % Configuring channels and exctracting their information
            [selected_channels,channel_names,sensor_numerical_inputs,datalogger_positions, app.channel_axis, device_error] = Config_devices(app, app.tableData, app.list_of_channels, session);
            
            % Check error of device names/channel positions
            if device_error
                return
            end

            % Defining the the sensor gains
            app.sensor_gains = cellfun(@(x) 1 ./ (x .* 1e-6), sensor_numerical_inputs, 'UniformOutput', false);

            % START OF LAUNCH =============================================
            
            pause(4);
            app.WaitLamp.Color = 'red';
            app.StartLaunchSequenceButton.Text = 'Drop';
            
            try

                % Starting data gathering
                [raw_data,app.time] = session.startForeground;

            catch
                
                % Catching error due top not configuring devices
                uialert(app.UIFigure, ...
                'No channels have been added to the session. Please configure the devices before starting gathering data.', ...
                'Session Error');

                app.WaitLamp.Color = 'green';
                app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';
                return

            end

            % Processing the raw_data into a cell array
            app.all_data = Process_raw_data(app,raw_data, selected_channels);

            app.WaitLamp.Color = 'green';
            app.StartLaunchSequenceButton.Text = 'Processing';

            % EDGE DETECTION ==============================================

            impact_points = Edge_detec(app,app.all_data,selected_channels,sensor_numerical_inputs);

            % Median of non-zero channels for each device
            app.median_dev_impact_points = Non_zero_median(app,impact_points);

            % Find indices of zero impact points
            zero_indices = find(cellfun(@(x) isnumeric(x) && isreal(x) && x == 0, app.median_dev_impact_points));
            
            % Show alert with nicely formatted message
            if ~isempty(zero_indices)
                % Convert indices to a comma-separated string
                index_str = strjoin(string(zero_indices), ', ');
                
                % Create a user-friendly message
                msg = sprintf('No impact points detected in the following devices (In order as shown in devices table): %s', index_str);
                
                % Display the alert
                uialert(app.UIFigure, msg, 'Zero Impact Points');

                app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';
                return
            end

            % SRS PROCESSING AND PLOTTING MAIN GRAPH ======================

            % Defines how long (In seconds) the shock will be analised for
            shock_duration = 0.2;

            % Defines how long (In data points) the shock will be analised for
            app.shock_length = shock_duration*sample_rate;

            % Number of devices
            number_of_devices = length(selected_channels);

            % Start the raw_SRS data
            raw_SRS = [];

            % Looping over all channels
            for device = 1:number_of_devices

                % Number of channels in  each device
                number_of_channels = length(selected_channels{device});

                for channel = 1:number_of_channels
                    
                    % Calculating SRS
                    edge_time = app.median_dev_impact_points{device};

                    calc_time = app.time(edge_time-100 : edge_time+app.shock_length);
                    calc_data = app.all_data{device}{channel}(edge_time-100:edge_time+app.shock_length)*(app.sensor_gains{device}(channel));
                    calc_data = detrend(calc_data,'constant');

                    [pos,neg,fn] = SRS_calc(app,calc_time,calc_data);

                    % Take the transpose and put in data structure
                    app.CH_pos{device}{channel} = pos.';
                    app.CH_neg{device}{channel} = neg.';
                    app.CH_fn{device}{channel} = fn.';

                    % Fill the raw_SRS matrix (I know this is changing size
                    % in each iteration however I did not have time to come
                    % up with a better way of doing this)
                    raw_SRS = [raw_SRS,pos',neg',fn'];

                end

            end

            % CONFIGURING TREE ============================================
            
            delete(app.Tree.Children);

            for device = 1:length(app.list_of_channels)
                    
                centralNode = uitreenode(app.Tree,"NodeData",[]);
                centralNode.Text = sprintf('Device %d', device);

                for channel = 1:app.list_of_channels(device)
                    
                    childNode = uitreenode(centralNode,"NodeData",[device,channel]);
                    childNode.Text = sprintf('Channel %d', channel);

                end

            end
            
            % PLOTTING MAIN GRAPH AND PERCENTAGE LABELS ===================

            axis_selected = [app.PlotXAxisCheckBox.Value, app.PlotYAxisCheckBox.Value, app.PlotZAxisCheckBox.Value];
            Averages = Plot_main_graph(app,app.list_of_channels, app.CH_pos, app.CH_neg, app.CH_fn, app.spec_freq, app.spec_SRS, axis_selected);
            
            % Define axis labels and corresponding parameters
            label_handles = {app.X_Axis_Label, app.Y_Axis_Label, app.Z_Axis_Label};

            % Preallocating the percentages
            percentages = [0,0,0];

            % Loop through each axis and change their labels
            for i = 1:3
                
                % if Averages have been calculated for this axis
                if ~isempty(Averages{i})
                    
                    % Calculates percentage
                    percentages(i) = Quality_check(app, Averages{i}, app.CH_fn{1}{1}, app.spec_SRS{i}, app.spec_freq{i});
                    label_handles{i}.Text = sprintf('%.0f%%', percentages(i));

                else
                    
                    label_handles{i}.Text = 'NaN';

                end

            end
            
            app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';

            % SAVE ALL DATA ===============================================

            clocknow = clock;
            app.timeid = datestr(clocknow,'yyyymmddHHMMSS');
            timeID = datestr(clock, 'yyyy-mm-dd HH:MM:SS');

            % Define the name of the new folder
            newFolder = fullfile(app.current_project_filename, [app.timeid,'_',app.current_config_filename(1:end-4)]);
            newFolder = [newFolder,'\'];

            % Create the folder
            if ~exist(newFolder, 'dir')
                mkdir(newFolder);
            end

            save_data = app.all_data;
            save_time = app.time;

            save(fullfile(newFolder, [app.timeid, '_processed_data.mat']), 'save_data', 'save_time');
            save(fullfile(newFolder, [app.timeid, '_raw_data.mat']), 'raw_data', 'save_time');
           
            CH_fn_save = app.CH_fn;
            CH_neg_save = app.CH_neg;
            CH_pos_save = app.CH_pos;

            save(fullfile(newFolder, [app.timeid, '_SRS_data.mat']), 'CH_neg_save', 'CH_pos_save', 'CH_fn_save');
            save(fullfile(newFolder, [app.timeid, '_raw_SRS.mat']), 'raw_SRS');

            % Save configuration file
            save_text_file = true;
            Save_config_file(app,app.config_library_location,app.current_config_filename,sample_rate, sample_duration, app.spec_freq, app.spec_SRS, desc_log, app.list_of_channels, app.tableData, app.columnHeaders, app.columnFormats, app.columnEditable, save_text_file)
            
            % Saving the png of main graph
            exportgraphics(app.MainGraph, fullfile(newFolder, [app.timeid, '_main_graph.png']));

            % Write txt file that describes config file
            Write_txt_file(app,newFolder,app.current_config_filename,timeID,percentages,sample_rate, sample_duration, app.spec_freq, app.spec_SRS, desc_log, app.list_of_channels, app.tableData)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)


            if ~isempty(app.Tree.SelectedNodes.NodeData)
                
                device = app.Tree.SelectedNodes.NodeData(1,1);
                channel = app.Tree.SelectedNodes.NodeData(1,2);

                % Determine which axes to use
                accel_axes = app.UIAxes;
                                
                % Plot acceleration
                edge_time = app.median_dev_impact_points{device};
                plot_time = app.time(edge_time-100 : edge_time+app.shock_length);

                plot(accel_axes, plot_time, app.all_data{device}{channel}(edge_time-100 : edge_time+app.shock_length)*app.sensor_gains{device}(channel))
                
                ylabel(accel_axes, 'Acceleration (g)')
                xlabel(accel_axes, 'Seconds')
                title(accel_axes, 'acceleration')

                % Determine which axes to use
                SRS_axes = app.UIAxes_2;
                                
                % Plot SR
                cla(SRS_axes);
                hold(SRS_axes, 'on');

                loglog(SRS_axes, app.CH_fn{device}{channel}, app.CH_pos{device}{channel},'--', 'Color', '#7099C2','DisplayName', 'Positive Response')
                loglog(SRS_axes, app.CH_fn{device}{channel}, -app.CH_neg{device}{channel},'-.', 'Color','#7099C2','DisplayName', 'Negative Response')
                loglog(SRS_axes, app.CH_fn{device}{channel}, (app.CH_pos{device}{channel}-app.CH_neg{device}{channel})/2,'Color','#0065C9', 'DisplayName', 'Mean Response')
                
                switch app.channel_axis{device}(channel)

                    case 'X'

                        freq = app.spec_freq_x;
                        SRS = app.spec_SRS_x;

                    case 'Y'

                        freq = app.spec_freq_y;
                        SRS = app.spec_SRS_y;

                    case 'Z'

                        freq = app.spec_freq_z;
                        SRS = app.spec_SRS_z;

                end

                loglog(SRS_axes,freq,SRS,'k','linewidth',1,'DisplayName','Target');
                loglog(SRS_axes,freq,SRS/sqrt(2),'--k','linewidth',1,'DisplayName','-3 dB','LineStyle','-.');
                loglog(SRS_axes,freq,SRS*2,'--k','linewidth',1,'DisplayName','+6 dB','LineStyle','--');
                
                set(SRS_axes, 'XScale', 'log', 'YScale', 'log')
                ylabel(SRS_axes, 'Peak Acceleration (g)')
                xlabel(SRS_axes, 'Frequency (Hz)')
                title(SRS_axes, 'Acceleration Shock Response Spectrum Q=10')
                legend(SRS_axes, 'show', 'Location', 'best')

                hold(SRS_axes, 'off');

            end
            
        end

        % Button pushed function: LoadConfigurationfileButton
        function LoadConfigurationfileButtonPushed(app, event)
            
            % Asking ur for the selection
            [file,location] = uigetfile('*.mat','Select a configuration file',app.config_library_location);

            % Checking if user selected anything
            if file == 0
                return
            end
            
            % Updating the current configuration
            app.current_config_filename = file;

            % Getting the full path to the file
            full_path = fullfile(location, file);
            
            % Reading the file
            Read_config_file(app,full_path);

        end

        % Value changed function: Spec_SRS_xEditField, 
        % ...and 5 other components
        function freq_and_SRS_input_changed(app, event)
            
            % The text box that triggered the event
            source = event.Source;
    
            inputName = source.Tag;
    
            % The new value entered by the user
            inputValue = source.Value; 
    
            Validate_input(app, inputName, inputValue, source);


        end

        % Value changed function: PlotXAxisCheckBox, PlotYAxisCheckBox, 
        % ...and 1 other component
        function Plot_Axis_Selection_Changed(app, event)
            
            % CHANGING VSISIBILITY OF MAIN GRAPH ==========================

            axis_selected = [app.PlotXAxisCheckBox.Value, app.PlotYAxisCheckBox.Value, app.PlotZAxisCheckBox.Value];
            
            % Looping thorugh all axis
            for axis = 1:length(app.main_plots)
                
                % Looping through each graph associated with each axis
                for graphs = 1:length(app.main_plots{axis})
                    
                    % If the graphs exis
                    if isgraphics(app.main_plots{axis}(graphs))
                        
                        % Make them visible or not depeding on user input
                        app.main_plots{axis}(graphs).Visible = axis_selected(axis);

                    end
                end
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            % ERROR HANDLING ==============================================
            
            % Getting the position of the dited cell
            row = event.Indices(1);
            col = event.Indices(2);
            
            % Getting the new and old inputs of the edited cell
            new_input = event.NewData;
            old_input = event.PreviousData;
            
            % Checks if user's input is within expected limits
            if col > 4*app.list_of_channels(row) + 1

                % Reverting to previous input
                app.tableData{row,col} = old_input;
                app.UITable.Data = app.tableData;

                error_message = "Input Error: Please do not input outside of designated channels.";
                uialert(app.UIFigure, error_message, 'Input Error');
                return
                
            end
            
            % Chekcing if it is the channel position input
            if rem(col,4) == 0
                
                % Checking if it is a positive integer (including 0)
                if ~isnumeric(new_input) || ~isreal(new_input) || new_input < 0 || floor(new_input) ~= new_input
                    
                    % Reverting to previous input
                    app.tableData{row,col} = old_input;
                    app.UITable.Data = app.tableData;
                    
                    % Display error message
                    error_message = "Input Error: Please input a positive integer." + newline + newline + "Valid input example: 2";
                    uialert(app.UIFigure, error_message, 'Input Error');
                    return
    
                end
                
                % Getting the number of channels in that device
                num_channels = app.list_of_channels(row);
                
                % Looping through all the channels
                for channel = 1:num_channels
                    
                    % if it finds a repeat of the channels position
                    % (excluding the input channel)
                    if app.tableData{row, 4*channel} == new_input && ~(4*channel == col)

                        % Reverting to previous input
                        app.tableData{row,col} = old_input;
                        app.UITable.Data = app.tableData;
                        
                        % Display error message
                        name = app.tableData{row,1};
                        error_message = sprintf('Input Error: Please check that each channel in device named "%s" has an unique position', name);
                        uialert(app.UIFigure, error_message, 'Input Error');
                        return

                    end

                end

            end
            
            % SAVING TABLE DATA ===========================================
            
            app.tableData = app.UITable.Data;

        end

        % Button pushed function: AddNewDeviceButton
        function AddNewDeviceButtonPushed(app, event)

            % ADDING A DEVICE TO THE END OF THE TABLE =====================
            
            app.list_of_channels = [app.list_of_channels,0];
            
            numDevices = length(app.list_of_channels);  

            % Check if tableData is empty or the first column is empty
            if isempty(app.tableData) || all(cellfun(@isempty, app.tableData(:, 1)))

                i = 1;

            else

                % Extract existing names safely
                existingNames = string(app.tableData(:, 1));
                baseName = "Dev";
                i = 1;
            
                % Loop until a unique name is found
                while any(existingNames == baseName + i)
                    i = i + 1;
                end

            end
            
            % Assign the unique name
            app.tableData{numDevices, 1} = sprintf('Dev%d', i);

            % CONFIGURE TABLE =============================================

            Configure_table(app, app.list_of_channels)

        end

        % Button pushed function: RemoveDeviceButton
        function RemoveDeviceButtonPushed(app, event)
            
            % ERROR HANDLING ==============================================

            if size(app.tableData, 1) == 0

                error_message = sprintf('Cannot remove device: The device list is empty. Please add a device first.');
                uialert(app.UIFigure, error_message, 'Logic Error');
                return;

            end

            device_names = app.tableData(:,1);
            message = 'Select the Device to Remove:';
            choice = Single_dropdown_popup(app,device_names,message, 'Remove Device');
            
            if isempty(choice)

                return

            end
            
            % REMOVING DEVICE AND CHANGING TABLE ==========================

            % Find the row index of the selected device
            row_index = find(strcmp(choice, device_names));

            app.list_of_channels(row_index) = [];
            app.tableData(row_index, :) = [];

            % CONFIGURE TABLE =============================================
            
            Configure_table(app, app.list_of_channels)

        end

        % Button pushed function: AddNewChannelButton
        function AddNewChannelButtonPushed(app, event)
            
            % ERROR HANDLING ==============================================
            
            % Checking if the number of devices isnt 0
            if size(app.tableData, 1) == 0

                error_message = sprintf('Cannot add channel: The device list is empty. Please add a device first.');
                uialert(app.UIFigure, error_message, 'Logic Error');
                return;

            end
            
            % EXTRACTING ALREADY USED SENSORS =============================

            % Initialize list of used sensors
            used_sensors = {};
            
            % Loop through each device
            for device_idx = 1:length(app.list_of_channels)
                num_channels = app.list_of_channels(device_idx);
                
                for channel_idx = 1:num_channels
                    % Calculate the column index for the channel name
                    col_idx = 4 * channel_idx - 2;
                    
                    % Extract the channel name
                    channel_name = app.tableData{device_idx, col_idx};
                    
                    % Add to used sensors list
                    used_sensors{end+1} = channel_name;

                end
            end

            % EXTRACTING AVAILABLE SENSORS ================================

            % Get all available sensor files
            files = dir(fullfile(app.sensor_library_location, '*.mat'));
            
            % Initialize list of available sensors
            available_sensors = {};
            
            % Loop through files and load channel names
            for pos = 1:length(files)
                file_path = fullfile(app.sensor_library_location, files(pos).name);
                data = load(file_path);
                
                if isfield(data, 'channel_name')
                    name = data.channel_name;
                    
                    % Only include if not already used
                    if ~any(strcmp(name, used_sensors))

                        available_sensors{end+1} = name;

                    end
                end
            end
            
            % Checking if there are any sensors available
            if isequal(available_sensors,{})
                
                uialert(app.UIFigure,"Cannot add channel: There are no available sensors","Logic Error");
                return

            end    

            % CREATING POP UP FOR USER INPUT ==============================
           
            % Message to be displayed 
            message = 'Select a device and channel:';

            device_names = app.tableData(:,1);

            % Creating pop up
            [device, sensor] = Double_dropdown_popup(app, device_names, available_sensors, message);
            
            % Checking if the user didnt cancel
            if isempty(device)

                return

            end

            % ADDING A CHANNEL TO THE END OF THE DEVICE ===================

            file_path = fullfile(app.sensor_library_location, [sensor, '.mat']);
            
            % Load the file
            data = load(file_path);
            
            % Extract the gain (adjustment)
            if isfield(data, 'channel_adjustment')

                sensor_gain = data.channel_adjustment;

            else

                warning('Gain not found in file: %s', file_path);

            end
            
            % Find the row index of the selected device
            row_index = find(strcmp(device, device_names));
            app.list_of_channels(row_index) = app.list_of_channels(row_index)+1;
            
            ch = app.list_of_channels(row_index);

            % Making sure that channel position is unique
            cols = 4:4:(4*(ch-1));
            positions = app.tableData(row_index, cols);
                
            pos = 1;
            while ismember(pos, cell2mat(positions))
                pos = pos + 1;
            end
            
            % Assigning values
            app.tableData{row_index, 4*ch-2} = sensor;    
            app.tableData{row_index, 4*ch-1} = sensor_gain;                         
            app.tableData{row_index, 4*ch} = pos;                         
            app.tableData{row_index, 4*ch+1} = 'X';
            
            % CONFIGURE TABLE =============================================

            Configure_table(app, app.list_of_channels)

        end

        % Button pushed function: SaveConfigurationfileButton
        function SaveConfigurationfileButtonPushed(app, event)

            sample_rate = app.sample_rateSpinner.Value*1000;
            sample_duration = app.sample_durationSpinner.Value;
            desc_log = app.LogFileDescriptionTextArea.Value;

            app.spec_freq_x = str2num(app.Spec_freq_xEditField.Value);
            app.spec_SRS_x = str2num(app.Spec_SRS_xEditField.Value);
            
            app.spec_freq_y = str2num(app.Spec_freq_yEditField.Value);
            app.spec_SRS_y = str2num(app.Spec_SRS_yEditField.Value);

            app.spec_freq_z = str2num(app.Spec_freq_zEditField.Value);
            app.spec_SRS_z = str2num(app.Spec_SRS_zEditField.Value);

            app.spec_freq = {app.spec_freq_x,app.spec_freq_y,app.spec_freq_z};
            app.spec_SRS = {app.spec_SRS_x,app.spec_SRS_y,app.spec_SRS_z};
            
            save_text_file = false;

            Save_config_file(app,app.config_library_location, app.current_config_filename, sample_rate, sample_duration, app.spec_freq, app.spec_SRS, desc_log, app.list_of_channels, app.tableData, app.columnHeaders, app.columnFormats, app.columnEditable, save_text_file)

        end

        % Button pushed function: NewProject
        function NewProjectButtonPushed(app, event)
            
            % Ask user for name
            prompt = {'Name of new project:'};
            dlgtitle = 'New Project Window';
            fieldsize = [1 80];
            definput = {''};
            project_name_cell = inputdlg(prompt, dlgtitle, fieldsize, definput);

            % Check if user cancelled or input is empty
            if isempty(project_name_cell) || isempty(strtrim(project_name_cell{1}))

                uialert(app.UIFigure, 'No valid project name provided. Project not saved.', 'Warning');
                app.ProjectLabel.Text = 'No Project Selected';
                return
                
            end
            
            % Extract and clean the project name
            project_name = strtrim(project_name_cell{1});

            % Create full path
            newFolderPath = fullfile(app.project_library_location, project_name);
            
            % Create folder if it doesn't exist
            if ~exist(newFolderPath, 'dir')

                mkdir(newFolderPath);

            else

                uialert(app.UIFigure, 'Project folder already exists.', 'Info');

            end
            
            % update the current project file path and app display
            app.current_project_filename = newFolderPath;
            app.ProjectLabel.Text = ["Current Project File: ", project_name];

        end

        % Button pushed function: SelectProject
        function SelectProjectButtonPushed(app, event)
        
        % Get all projects in the folder
        projects = dir(app.project_library_location);
        
        % Filter to get only folders, excluding '.' and '..'
        projectFlags = [projects.isdir];
        projectNames = {projects(projectFlags).name};
        projectNames = projectNames(~ismember(projectNames, {'.', '..'}));
        
        % Ask user to choose a project
        project_choice = Single_dropdown_popup(app, projectNames, 'Please choose the project:', 'Project Selection');
        app.ProjectLabel.Text = ["Current Project File: ", project_choice];

        % Get full path of the selected project
        chosenProjectPath = fullfile(app.project_library_location, project_choice);

        % Save that as the current project
        app.current_project_filename = char(chosenProjectPath);
        
        end

        % Button pushed function: NewConfigurationfileButton
        function NewConfigurationfileButtonPushed(app, event)
            
            % Ask user for name
            prompt = {'This action will change the current configuration. If you wish to save the current inputs to the previous configuration, please cancel now. Otherwise, enter a name for the new configuration to proceed.'};
            dlgtitle = 'New Configuration File';
            fieldsize = [1 80];
            definput = {''};
            file_name = inputdlg(prompt, dlgtitle, fieldsize, definput);

            % Check if user cancelled or left the input empty
            if isempty(file_name) || isempty(file_name{1}) || all(isspace(file_name{1}))

                uialert(app.UIFigure, 'No configuration name was provided. The operation has been canceled.', 'Warning');
                return

            end
 
            % Clean and prepare name
            configName = strtrim(file_name{1});
            fileName = [configName, '.mat'];
                
            % Check for uniqueness
            existingFiles = dir(fullfile(app.config_library_location, '*.mat'));
            existingNames = {existingFiles.name};
                
            if any(strcmpi(existingNames, fileName))  % Case-insensitive match

                uialert(app.UIFigure, ['A configuration named "' configName '" already exists. Please choose a different name.'], 'Error', 'Icon', 'error');
                return;

            end
                
            % If it passes through the tests then update the current
            % file name and display it
            app.current_config_filename = char(file_name);
            app.ConfigLabel.Text = ["Current Config File: ",file_name];
            
            uialert(app.UIFigure, 'New configuration file created.', 'Info', 'Icon', 'success');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainOperationsTab = uitab(app.TabGroup);
            app.MainOperationsTab.Title = 'Main Operations';
            app.MainGraph = uiaxes(app.MainOperationsTab);
            title(app.MainGraph, 'Title')
            xlabel(app.MainGraph, 'X')
            ylabel(app.MainGraph, 'Y')
            zlabel(app.MainGraph, 'Z')
            app.MainPlotLabel = uilabel(app.MainOperationsTab);
            app.MainPlotLabel.Text = '------------------------------------------------ Main Plot -------------------------------------------';
            app.StatusLabel = uilabel(app.MainOperationsTab);
            app.StatusLabel.Text = '------------ Status ------------';
            app.WaitLamp = uilamp(app.MainOperationsTab);
            app.StartLaunchSequenceButton = uibutton(app.MainOperationsTab, 'push');
            app.StartLaunchSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @Launch_callback, true);
            app.StartLaunchSequenceButton.Tooltip = {'Starts the launch process'};
            app.StartLaunchSequenceButton.Text = 'Start Launch Sequence';
            app.MainPlotAxesLabel = uilabel(app.MainOperationsTab);
            app.MainPlotAxesLabel.Text = '------ Main Plot Axes ------';
            app.PlotXAxisCheckBox = uicheckbox(app.MainOperationsTab);
            app.PlotXAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plot_Axis_Selection_Changed, true);
            app.PlotXAxisCheckBox.Text = 'Plot X Axis';
            app.PlotXAxisCheckBox.Value = true;
            app.PlotYAxisCheckBox = uicheckbox(app.MainOperationsTab);
            app.PlotYAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plot_Axis_Selection_Changed, true);
            app.PlotYAxisCheckBox.Text = 'Plot Y Axis';
            app.PlotYAxisCheckBox.Value = true;
            app.PlotZAxisCheckBox = uicheckbox(app.MainOperationsTab);
            app.PlotZAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plot_Axis_Selection_Changed, true);
            app.PlotZAxisCheckBox.Text = 'Plot Z Axis';
            app.PlotZAxisCheckBox.Value = true;
            app.X_Axis_Label = uilabel(app.MainOperationsTab);
            app.X_Axis_Label.Tooltip = {'Percentage of X axis SRS data points over the target'};
            app.X_Axis_Label.Text = 'NaN';
            app.Y_Axis_Label = uilabel(app.MainOperationsTab);
            app.Y_Axis_Label.Tooltip = {'Percentage of Y axis SRS data points over the target'};
            app.Y_Axis_Label.Text = 'NaN';
            app.Z_Axis_Label = uilabel(app.MainOperationsTab);
            app.Z_Axis_Label.Tooltip = {'Percentage of Z axis SRS data points over the target'};
            app.Z_Axis_Label.Text = 'NaN';
            app.DataFileConfigurationsLabel = uilabel(app.MainOperationsTab);
            app.DataFileConfigurationsLabel.Text = '-------------------- Data File Configurations --------------';
            app.LogFileDescriptionLabel = uilabel(app.MainOperationsTab);
            app.LogFileDescriptionLabel.Text = 'Log File Description: ';
            app.LogFileDescriptionTextArea = uitextarea(app.MainOperationsTab);
            app.LogFileDescriptionTextArea.Tooltip = {'Enter any comments you''d like to include. These will be saved in the description text file for the run.'};
            app.NewProject = uibutton(app.MainOperationsTab, 'push');
            app.NewProject.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProject.Tooltip = {'Creates a new project'};
            app.NewProject.Text = 'New Project';
            app.SelectProject = uibutton(app.MainOperationsTab, 'push');
            app.SelectProject.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectButtonPushed, true);
            app.SelectProject.Tooltip = {'Selects the project specified by the user'};
            app.SelectProject.Text = 'Select Project';
            app.ProjectLabel = uilabel(app.MainOperationsTab);
            app.ProjectLabel.Text = 'No Project Selected';
            app.ConfigurationsTab = uitab(app.TabGroup);
            app.ConfigurationsTab.Title = 'Configurations';
            app.kHzLabel = uilabel(app.ConfigurationsTab);
            app.kHzLabel.Text = '(kHz)';
            app.ConfigurationsLabel = uilabel(app.ConfigurationsTab);
            app.ConfigurationsLabel.Text = '------------------------------------------------------------------------- Configurations -----------------------------------------------------------------------------------';
            app.sLabel = uilabel(app.ConfigurationsTab);
            app.sLabel.Text = '(s)';
            app.LoadConfigurationfileButton = uibutton(app.ConfigurationsTab, 'push');
            app.LoadConfigurationfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigurationfileButtonPushed, true);
            app.LoadConfigurationfileButton.Tooltip = {'Loads the chosen configuration'};
            app.LoadConfigurationfileButton.Text = 'Load Configuration file';
            app.sample_rateSpinnerLabel = uilabel(app.ConfigurationsTab);
            app.sample_rateSpinnerLabel.Text = 'sample_rate';
            app.sample_rateSpinner = uispinner(app.ConfigurationsTab);
            app.sample_rateSpinner.Limits = [1 999];
            app.sample_rateSpinner.Tooltip = {'Sample rate of the datalogger'};
            app.sample_rateSpinner.Value = 80;
            app.sample_durationSpinnerLabel = uilabel(app.ConfigurationsTab);
            app.sample_durationSpinnerLabel.Text = 'sample_duration';
            app.sample_durationSpinner = uispinner(app.ConfigurationsTab);
            app.sample_durationSpinner.Limits = [1 99];
            app.sample_durationSpinner.Tooltip = {'Sample duration of the datalogger'};
            app.sample_durationSpinner.Value = 5;
            app.Spec_freq_xEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_freq_xEditFieldLabel.Text = 'Spec_freq_x';
            app.Spec_freq_xEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_freq_xEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_freq_xEditField.Tag = 'freq_x';
            app.Spec_freq_xEditField.Tooltip = {'Comma separated values for the X axis target frequencies of the SRS'};
            app.Spec_freq_yEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_freq_yEditFieldLabel.Text = 'Spec_freq_y';
            app.Spec_freq_yEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_freq_yEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_freq_yEditField.Tag = 'freq_y';
            app.Spec_freq_yEditField.Tooltip = {'Comma separated values for the Y axis target frequencies of the SRS'};
            app.Spec_freq_zEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_freq_zEditFieldLabel.Tag = 'freq-z';
            app.Spec_freq_zEditFieldLabel.Text = 'Spec_freq_z';
            app.Spec_freq_zEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_freq_zEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_freq_zEditField.Tag = 'freq_z';
            app.Spec_freq_zEditField.Tooltip = {'Comma separated values for the Z axis target frequencies of the SRS'};
            app.Spec_SRS_xEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_SRS_xEditFieldLabel.Text = 'Spec_SRS_x';
            app.Spec_SRS_xEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_SRS_xEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_SRS_xEditField.Tag = 'SRS_x';
            app.Spec_SRS_xEditField.Tooltip = {'Comma separated values for the X axis target amplitudes of the SRS'};
            app.Spec_SRS_yEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_SRS_yEditFieldLabel.Text = 'Spec_SRS_y';
            app.Spec_SRS_yEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_SRS_yEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_SRS_yEditField.Tag = 'SRS_y';
            app.Spec_SRS_yEditField.Tooltip = {'Comma separated values for the Y axis target amplitudes of the SRS'};
            app.Spec_SRS_zEditFieldLabel = uilabel(app.ConfigurationsTab);
            app.Spec_SRS_zEditFieldLabel.Text = 'Spec_SRS_z';
            app.Spec_SRS_zEditField = uieditfield(app.ConfigurationsTab, 'text');
            app.Spec_SRS_zEditField.ValueChangedFcn = createCallbackFcn(app, @freq_and_SRS_input_changed, true);
            app.Spec_SRS_zEditField.Tag = 'SRS_z';
            app.Spec_SRS_zEditField.Tooltip = {'Comma separated values for the Z axis target amplitudes of the SRS'};
            app.UITable = uitable(app.ConfigurationsTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tooltip = {'Device table, it contains the device and channel information'};
            app.DevicesLabel = uilabel(app.ConfigurationsTab);
            app.DevicesLabel.Text = '-------------------------------------------------------------------------------- Devices -------------------------------------------------------------------------------------';
            app.SaveConfigurationfileButton = uibutton(app.ConfigurationsTab, 'push');
            app.SaveConfigurationfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationfileButtonPushed, true);
            app.SaveConfigurationfileButton.Tooltip = {'Saves the current inputs into the current configuration file'};
            app.SaveConfigurationfileButton.Text = 'Save Configuration file';
            app.AddNewDeviceButton = uibutton(app.ConfigurationsTab, 'push');
            app.AddNewDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewDeviceButtonPushed, true);
            app.AddNewDeviceButton.Tooltip = {'Adds a new device to the device table'};
            app.AddNewDeviceButton.Text = 'Add New Device';
            app.RemoveDeviceButton = uibutton(app.ConfigurationsTab, 'push');
            app.RemoveDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDeviceButtonPushed, true);
            app.RemoveDeviceButton.Tooltip = {'Removes a chosen device from the device table'};
            app.RemoveDeviceButton.Text = 'Remove Device';
            app.AddNewChannelButton = uibutton(app.ConfigurationsTab, 'push');
            app.AddNewChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewChannelButtonPushed, true);
            app.AddNewChannelButton.Tooltip = {'Adds a new channel to a chosen device in the device table'};
            app.AddNewChannelButton.Text = 'Add New Channel';
            app.ConfigLabel = uilabel(app.ConfigurationsTab);
            app.ConfigLabel.Text = 'No Configuration Selected';
            app.NewConfigurationfileButton = uibutton(app.ConfigurationsTab, 'push');
            app.NewConfigurationfileButton.ButtonPushedFcn = createCallbackFcn(app, @NewConfigurationfileButtonPushed, true);
            app.NewConfigurationfileButton.Tooltip = {'Creates and assigns a new configuration file, but does not change the user inputs'};
            app.NewConfigurationfileButton.Text = 'New Configuration file';
            app.HzLabel = uilabel(app.ConfigurationsTab);
            app.HzLabel.Text = '(Hz)';
            app.HzLabel_2 = uilabel(app.ConfigurationsTab);
            app.HzLabel_2.Text = '(Hz)';
            app.HzLabel_3 = uilabel(app.ConfigurationsTab);
            app.HzLabel_3.Text = '(Hz)';
            app.gLabel = uilabel(app.ConfigurationsTab);
            app.gLabel.Text = '(g)';
            app.gLabel_2 = uilabel(app.ConfigurationsTab);
            app.gLabel_2.Text = '(g)';
            app.gLabel_3 = uilabel(app.ConfigurationsTab);
            app.gLabel_3.Text = '(g)';
            app.RawPlotsTab = uitab(app.TabGroup);
            app.RawPlotsTab.Title = 'Raw Plots';
            app.UIAxes = uiaxes(app.RawPlotsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RawPlotsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Tree = uitree(app.RawPlotsTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Left-click on the desired channel to select it.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shock_Test_App_Unfinished_week8_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end