classdef Shock_Test_App_Unfinished_week3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainOperationsTab               matlab.ui.container.Tab
        Spec_SRS_zEditField             matlab.ui.control.EditField
        Spec_SRS_zEditFieldLabel        matlab.ui.control.Label
        Spec_SRS_yEditField             matlab.ui.control.EditField
        Spec_SRS_yEditFieldLabel        matlab.ui.control.Label
        Spec_SRS_xEditField             matlab.ui.control.EditField
        Spec_SRS_xEditFieldLabel        matlab.ui.control.Label
        Spec_SRSEditField               matlab.ui.control.EditField
        Spec_SRSEditFieldLabel          matlab.ui.control.Label
        Spec_freq_zEditField            matlab.ui.control.EditField
        Spec_freq_zEditFieldLabel       matlab.ui.control.Label
        Spec_freq_yEditField            matlab.ui.control.EditField
        Spec_freq_yEditFieldLabel       matlab.ui.control.Label
        Spec_freq_xEditField            matlab.ui.control.EditField
        Spec_freq_xEditFieldLabel       matlab.ui.control.Label
        Spec_freqEditField              matlab.ui.control.EditField
        Spec_freqEditFieldLabel         matlab.ui.control.Label
        DevicesLabel                    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        NumberofChannelsEditField       matlab.ui.control.EditField
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        LocationofdatafileEditField     matlab.ui.control.EditField
        LocationofdatafileLabel         matlab.ui.control.Label
        sample_durationSpinner          matlab.ui.control.Spinner
        sample_durationSpinnerLabel     matlab.ui.control.Label
        sample_rateSpinner              matlab.ui.control.Spinner
        sample_rateSpinnerLabel         matlab.ui.control.Label
        LaunchButton                    matlab.ui.control.Button
        WaitLamp                        matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        MainPlotLabel                   matlab.ui.control.Label
        DescriptionforlogfileCheckBox   matlab.ui.control.CheckBox
        PlotDataCheckBox                matlab.ui.control.CheckBox
        sLabel                          matlab.ui.control.Label
        kHLabel                         matlab.ui.control.Label
        ConfigurationsLabel             matlab.ui.control.Label
        MainGraph                       matlab.ui.control.UIAxes
        IndividualGraphsTab             matlab.ui.container.Tab
        RawDataGraphsTab                matlab.ui.container.Tab
        Device2Label_2                  matlab.ui.control.Label
        Device1Label_2                  matlab.ui.control.Label
        UIAxes_12                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device_list = daqlist("ni");
    end
    
    methods (Access = private)
        
        function impact_points = Edge_detec(app,all_data,selected_channels,sensor_numeric_inputs)
            % Edge detection of the raw signal to specify where to analyse the data
            % * all_data is a cell array containing the data for each
            % channel (for example: all_data{2}{3} is the data for the 3rd
            % channel of the 2nd device)
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * sensor_numeric_inputs is a cell array containing P lists
            % containing the numerical adjustments for each channel (for
            % example: sensor_numeric_inputs{2}(3) is the adjustment value
            % for the 3rd channel in the 2nd device)
            % * impact_points is a cell array that has N lists of the 
            % outputs of all the impact points for each channel in the  
            % device, where N is the number of devices. If channel is not 
            % selected then it will default to 0 (for example:
            % impact_points{2}(3) is the detected impact time for the 3rd
            % channel of the 2nd device)
            
            % Number of devices
            number_of_devices = length(selected_channels);

            % Preallocating the impact_points cell array (all 0s)
            impact_points = cellfun(@(x) zeros(size(x)), originalCell, 'UniformOutput', false);

            % Looping over all channels
            for device = 1:number_of_devices
                
                % Number of channels in  each device
                number_of_channels = length(selected_channels{device});

                for channel = 1:number_of_channels
                
                    % Checking if channel is selected by the user
                    if selected_channels{device}(channel)
                        
                        % Finding the difference between sucssecive data points
                        % of each channel
                        test = diff(all_data{device}{channel}*sensor_numeric_inputs{device}(channel));
                        
                        % Looping over the whole data
                        for i_loop=1:length(test)
    
                            if(abs(test(i_loop)) > 5) && i_loop > 1000

                                impact_points{device}(channel) = i_loop;
                                break

                            end
                        end
                    end
                end
            end

        end
        
        function special_median = Non_zero_median(app,input_array)
            % Calculates the rownded down median of the non-zero entries
            % * input_array is a cell array made of lists of numbers 
            % * special_median is a cell array with the same number of
            % cells as "input_array" but with the integer median 
            % (rounded down) of the non-zero entries for that list (for
            % example: if input_array = {[1,2,3,0,5],[0,0],[1,1,3]} then
            % special median = {2,0,1})


            % New list without zero entries
            non_zero_list = cellfun(@(x) x(x ~= 0),input_array,'UniformOutput',false);

            % Median is calculated with new list 
            special_median = cellfun(@median,non_zero_list,'UniformOutput',false);


        end
        
        function [x_pos,x_neg,fn] = SRS_calc(app,Input)
            % disp(' ')
            % disp(' srs.m   ver 2.0   July 3, 2006')
            % disp(' by Tom Irvine   Email: tomirvine@aol.com')
            % disp(' ')
            % disp(' This program calculates the shock response spectrum')
            % disp(' of an acceleration time history, which is pre-loaded into Matlab.')
            % disp(' The time history must have two columns: time(sec) & acceleration')
            % disp(' ')

            THM = Input;

            t=double(THM(:,1));
            y=double(THM(:,2));

            tmx=max(t);
            tmi=min(t);
            n = length(y);

            dt=(tmx-tmi)/(n-1);
            sr=1./dt;

            fn(1) = 20;
            if fn(1)>sr/30.
                fn(1)=sr/30.;
            end
           
            Q = 10;
            damp=1./(2.*Q);

            ialgorithm=1;

            tmax=(tmx-tmi) + 1./fn(1);
            limit = round( tmax/dt );
            n=limit;
            yy=zeros(1,limit);
            for i=1:length(y)
                    yy(i)=y(i);
            end    
            for j=1:1000

                omega=2.*pi*fn(j);
                omegad=omega*sqrt(1.-(damp^2));
                cosd=cos(omegad*dt);
                sind=sin(omegad*dt);
                domegadt=damp*omega*dt;

                if(ialgorithm==1)
                    a1(j)=2.*exp(-domegadt)*cosd;
                    a2(j)=-exp(-2.*domegadt);
                    b1(j)=2.*domegadt;
                    b2(j)=omega*dt*exp(-domegadt);
                    b2(j)=b2(j)*( (omega/omegad)*(1.-2.*(damp^2))*sind -2.*damp*cosd );
                    b3(j)=0;

                else
	                E=exp(-damp*omega*dt);
		            K=omegad*dt;
		            C=E*cos(K);
		            S=E*sin(K);
		            Sp=S/K;

	                a1(j)=2*C;
		            a2(j)=-E^2;
		            b1(j)=1.-Sp;
		            b2(j)=2.*(Sp-C);
		            b3(j)=E^2-Sp;
                end
                forward=[ b1(j),  b2(j),  b3(j) ];    
                back   =[     1, -a1(j), -a2(j) ];    
    
                resp=filter(forward,back,yy);

                x_pos(j)= max(resp);
                x_neg(j)= min(resp);
  
                jnum=j; 
                if  fn(j) > sr/8.
                    break
                end
                fn(j+1)=fn(1)*(2. ^ (j*(1./12.)));    
            end

        end
        
        function [selected_channels,channel_names,sensor_numerical_inputs,datalogger_positions] = Config_devices(app, input_table, number_of_channels, session)
            % Configures all the channels that are selected by the user and
            % outputs the list of selected channels
            % * input_table is the table of input channels by the user, each
            % column is structed as follows: device name, channel 1 name,
            % channel 1 adjustment, channel 1 channel in datalogger, and so
            % on (for example: input_table{5,3} is the channel 2 name of
            % the 3rd device)
            % * number_of_channels is a 1D vector containing the expected
            % number of channels in each device in order (for example:
            % number_of_channels(2) is the expected number of channels in
            % device 2)
            % * session is the name of the DAQ session
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * channel_names is a cell array containing P lists
            % containing the names of the channels (for example:
            % channel_names{2}(3) is the name of the 3rd channel
            % in the 2nd device)
            % * sensor_numerical_inputs is a cell array containing P lists
            % containing the adjustments values of the channels (for example:
            % sensor_numerical_inputs{2}(3) is the adjustment value of the 
            % 3rd channel in the 2nd device)
            % * datalogger_positions is a cell array containing P lists
            % containing the datalogger channel position of the channels 
            % (for example: datalogger_positions{2}(3) is the name of the 
            % 3rd channel in the 2nd device)

            % Number of devices
            number_of_devices = length(number_of_channels);
            
            % Initialize output
            selected_channels = cell(1, number_of_devices);
            channel_names = cell(1, number_of_devices);
            sensor_numerical_inputs = cell(1, number_of_devices);
            datalogger_positions = cell(1, number_of_devices);
            
            % Loop over all devices
            for device = 1:number_of_devices

                selected_channels{device} = false(1, number_of_channels(device));
                
                % Loop over all channels
                for channel = 1:number_of_channels(device)
                    
                    % Getting the name, adjustment and datalogger channel number
                    channel_name = input_table{3*channel-1,device};
                    channel_adjustment = input_table{3*channel,device};
                    datalogger_channel = input_table{3*Channel+1,devide};
                    
                    channel_names{device}(channel) = channel_name;
                    sensor_numerical_inputs{device}(channel) = channel_adjustment;
                    datalogger_positions{device}(channel) = datalogger_channel;

                    % If the channel name is "selected"
                    if(~strcmp(channel_name, '') && ~strcmp(channel_name, 'NaN'))

                        addAnalogInputChannel(session,channel_name, datalogger_channel, 'Voltage');
                        selected_channels{device}(channel) = true;

                    end
                end
            end

        end
        
        function all_data = Process_raw_data(app,raw_data,selected_channels)
            % * raw_data is a NxM matrix containing the N datapoints of the
            % M activated channels in order (for example: raw_data(:,2) is
            % the column of data measured by the 2nd channel to be activated)
            % * selected_channels is a cell array containing P lists
            % containing true/false representing if the Pth device
            % channels are selected by user (for example:
            % selected_channels{2}(3) is the bool value for the 3rd channel
            % in the 2nd device)
            % * all_data is a cell array containing the data for each
            % channel (for example: all_data{2}{3} is the data for the 3rd
            % channel of the 2nd device)

            % Number of devices
            number_of_devices = length(selected_channels);

            data_lenght = size(raw_data,1);

            counter = 1;

            for device = 1:number_of_devices
                
                % Number of channels in  each device
                number_of_channels = length(selected_channels{device});

                for channel = 1:number_of_channels
                    
                    if selected_channels{device}(channel)
                    
                        all_data{device}{channel} = raw_data(:,counter);
                        counter = counter + 1;

                    else
                        
                        all_data{device}{channel} = zeros(data_lenght,1);


                    end
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaunchButton
        function Launch_callback(app, event)
            

            % FILE CONFIGURATION ==========================================

            % File location for the program
            filename = app.LocationofdatafileEditField.Value;

            filename = [filename,'\'];

            % Starts the config for the dataloggers
            session = daq.createSession('ni');

            % CONFIGURING ALL CHANNELS AND EXTRACTING DATA ================

            session.Rate = app.sample_rateSpinner.Value*1000;
            session.DurationInSeconds = app.sample_durationSpinner.Value;

            plot_data = app.PlotDataCheckBox.Value;
            description_file = app.DescriptionforlogfileCheckBox.Value;

            spec_freq = str2num(app.Spec_freqEditField.Value);
            spec_SRS = str2num(app.Spec_SRSEditField.Value);
            
            % 1D array containing the number of channels of each device
            number_of_channels = str2num(app.NumberofChannelsEditField.Value);
            
            % The data Input table
            table_data = app.UITable.Data;
            
            % Configuring channels and exctracting their information
            [selected_channels,channel_names,sensor_numerical_inputs,datalogger_positions] = Config_devices(app, table_data, number_of_channels, session);
            
            % Defining the the sensor gains
            sensor_gains = cellfun(@(x) 1 ./ (x .* 1e-6), sensor_numerical_inputs, 'UniformOutput', false);

            % START OF LAUNCH =============================================
                     
            % Start timer
            app.WaitLamp.Color = 'yellow';
            app.LaunchButton.Text = 'Wait';
            pause(2)

            app.WaitLamp.Color = 'red';
            app.LaunchButton.Text = 'Drop';
            
            % Starting data gathering
            [raw_data,time] = session.startForeground;
            
            % Processing the raw_data into a cell array
            all_data = Process_raw_data(app,raw_data);
            pause(1)
            
            app.WaitLamp.Color = 'green';
            app.LaunchButton.Text = 'Processing';

            % Save all data ===============================================

            clocknow = clock;
            timeid = datestr(clocknow,'yyyymmddHHMMSS');
            save([filename,timeid,'.mat'],'all_data','time')
            
            sample_rate = session.Rate/1000;

            % Edge detection ==============================================

            impact_points = Edge_detec(app,all_data,selected_channels,sensor_numerical_inputs);
            
            % Median of non-zero channels for each device
            median_dev_impact_points = Non_zero_median(app,impact_points);

            % Start of Plotting ===========================================
            
            % Defines how long (In data points) the shock will be analised for
            shock_length = 10000;
            
            % Loops through all the expected devices
            for device_number=1:expected_devices

                % Loops through each of the channels of the device
                for channel=1:3

                    gain = sensor_gains(channel+((device_number-1)*3));
                    
                    % If the loop is going through dev 1/2 use median of
                    % selected channels for such device to define the start
                    % of data collection
                    if(device_number==1)
                        data = cat(2,time([median_dev1-100:shock_length+median_dev1]),all_data([median_dev1-100:shock_length+median_dev1],channel+((device_number-1)*3))*gain);
                    else
                        data = cat(2,time([median_dev2-100:shock_length+median_dev2]),all_data([median_dev2-100:shock_length+median_dev2],channel+((device_number-1)*3))*gain);
                    end
                    
                    data(:,2) = detrend(data(:,2),'constant');
                    velocity=cumtrapz(data(:,1),data(:,2))*9.8;

                    % Determine which axes to use
                    accel_axes = app.(sprintf('UIAxes_%d', (device_number-1)*6 + (channel-1)*2 + 1));
                    vel_axes   = app.(sprintf('UIAxes_%d', (device_number-1)*6 + (channel-1)*2 + 2));
                    
                    % Plot acceleration
                    plot(accel_axes, data(:,1), data(:,2))
                    xlim(accel_axes, [data(1,1) data(end,1)])
                    ylabel(accel_axes, 'Acceleration (g)')
                    xlabel(accel_axes, 'Seconds')
                    title(accel_axes, 'acceleration')
                    
                    % Plot velocity
                    plot(vel_axes, data(:,1), velocity, 'b')
                    xlim(vel_axes, [data(1,1) data(end,1)])
                    xlabel(vel_axes, 'Seconds')
                    title(vel_axes, 'velocity(m/s)')
                    
                    [x_pos,x_neg,fn] = SRS_calc(app,data);
                    CH_neg(:,channel+((device_number-1)*3)) = x_neg;
                    CH_pos(:,channel+((device_number-1)*3)) = x_pos;
                    CH_fn(:,channel+((device_number-1)*3)) = fn;        
                end
            end
            
            save([filename,timeid,'_SRS_data.mat'],'CH_neg','CH_pos','CH_fn')
            
            if(expected_devices == 2)

            % Generate average curves of the +ve and -ve sensors
            CH_f = CH_fn(:,1);
            CH_X_avg = (CH_pos(:,1)+CH_pos(:,4)-CH_neg(:,1)-CH_neg(:,4))/4;
            CH_Y_avg = (CH_pos(:,2)+CH_pos(:,5)-CH_neg(:,2)-CH_neg(:,5))/4;
            CH_Z_avg = (CH_pos(:,3)+CH_pos(:,6)-CH_neg(:,3)-CH_neg(:,6))/4;
                
                cla(app.MainGraph);
                hold(app.MainGraph, 'on');
                loglog(app.MainGraph,CH_f,CH_X_avg,'LineWidth',2,'DisplayName','X axis',...
                    'Color',[0.0745098039215686 0.623529411764706 1]);
                loglog(app.MainGraph,CH_f,CH_Y_avg,'LineWidth',2,'DisplayName','Y axis',...
                    'Color',[1 0.411764705882353 0.16078431372549]);
                loglog(app.MainGraph,CH_f,CH_Z_avg,'LineWidth',2,'DisplayName','Z axis',...
                    'Color',[0.392156862745098 0.831372549019608 0.0745098039215686]);
  
                
                % Plot the spec SRS + tolerance bands
                
                loglog(app.MainGraph,spec_freq,spec_SRS,'k','linewidth',1,'DisplayName','Target');
                loglog(app.MainGraph,spec_freq,spec_SRS/sqrt(2),'-.k','linewidth',1,'DisplayName','-3 dB','LineStyle','-.');
                loglog(app.MainGraph,spec_freq,spec_SRS*2,'--k','linewidth',1,'DisplayName','+6 dB','LineStyle','--');
                
                % Create zlabel
                zlabel(app.MainGraph,'ZLabel');
                
                % Create ylabel
                ylabel(app.MainGraph,{'Peak Acceleration (g)'},'HorizontalAlignment','center');
                
                % Create xlabel
                xlabel(app.MainGraph,{'Frequency (Hz)'},'HorizontalAlignment','center');
                
                % Create title
                title(app.MainGraph,'Acceleration Shock Response Spectrum Q=10',...
                    'HorizontalAlignment','center',...
                    'FontWeight','bold');
                hold(app.MainGraph, 'off');
            end

            % At the end 
            app.LaunchButton.Enable = "on";
            app.LaunchButton.Text = 'Launch';


        end

        % Value changed function: NumberofChannelsEditField
        function NumberofChannelsEditFieldValueChanged(app, event)
            
            % Defining a list of selected channels
            number_of_channels = str2num(app.NumberofChannelsEditField.Value);
            
            % Determine number of columns
            numCols = length(number_of_channels);
            
            % Determine number of rows for each column one extra for name          
            numRows = 3 * number_of_channels + 1;
            
            % Find the maximum number of rows needed
            maxRows = max(numRows);
            
            % Initialize cell array with empty strings
            tableData = cell(maxRows, numCols);
            
            % Fill each column with placeholder values
            for col = 1:numCols
                for row = 1:numRows(col)
                    tableData{row, col} = 'NaN'; 
                end
            end

            % Create column headers like 'Device 1', 'Device 2', ...
            columnHeaders = arrayfun(@(i) sprintf('Device %d', i), 1:numCols, 'UniformOutput', false);
            
            % Set the headers
            app.UITable.ColumnName = columnHeaders;

            % Assign to UITable in App Designer
            app.UITable.Data = tableData;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainOperationsTab = uitab(app.TabGroup);
            app.MainOperationsTab.Title = 'Main Operations';
            app.MainGraph = uiaxes(app.MainOperationsTab);
            title(app.MainGraph, 'Title')
            xlabel(app.MainGraph, 'X')
            ylabel(app.MainGraph, 'Y')
            zlabel(app.MainGraph, 'Z')
            app.ConfigurationsLabel = uilabel(app.MainOperationsTab);
            app.ConfigurationsLabel.Text = '----------------------------------- Configurations ----------------------------------------';
            app.kHLabel = uilabel(app.MainOperationsTab);
            app.kHLabel.Text = '(kH)';
            app.sLabel = uilabel(app.MainOperationsTab);
            app.sLabel.Text = '(s)';
            app.PlotDataCheckBox = uicheckbox(app.MainOperationsTab);
            app.PlotDataCheckBox.Text = '  Plot Data';
            app.PlotDataCheckBox.Value = true;
            app.DescriptionforlogfileCheckBox = uicheckbox(app.MainOperationsTab);
            app.DescriptionforlogfileCheckBox.Text = '  Description for log file';
            app.DescriptionforlogfileCheckBox.Value = true;
            app.MainPlotLabel = uilabel(app.MainOperationsTab);
            app.MainPlotLabel.Text = '---------------------- Main Plot ---------------------';
            app.StatusLabel = uilabel(app.MainOperationsTab);
            app.StatusLabel.Text = '-------------- Status ----------------';
            app.WaitLamp = uilamp(app.MainOperationsTab);
            app.LaunchButton = uibutton(app.MainOperationsTab, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @Launch_callback, true);
            app.LaunchButton.Text = 'Launch';
            app.sample_rateSpinnerLabel = uilabel(app.MainOperationsTab);
            app.sample_rateSpinnerLabel.Text = 'sample_rate';
            app.sample_rateSpinner = uispinner(app.MainOperationsTab);
            app.sample_rateSpinner.Limits = [1 999];
            app.sample_rateSpinner.Value = 80;
            app.sample_durationSpinnerLabel = uilabel(app.MainOperationsTab);
            app.sample_durationSpinnerLabel.Text = 'sample_duration';
            app.sample_durationSpinner = uispinner(app.MainOperationsTab);
            app.sample_durationSpinner.Limits = [1 9];
            app.sample_durationSpinner.Value = 5;
            app.LocationofdatafileLabel = uilabel(app.MainOperationsTab);
            app.LocationofdatafileLabel.Text = 'Location of data file';
            app.LocationofdatafileEditField = uieditfield(app.MainOperationsTab, 'text');
            app.LocationofdatafileEditField.Value = 'C:\Users\kebl8311\Desktop\WorkArround';
            app.NumberofChannelsEditFieldLabel = uilabel(app.MainOperationsTab);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels';
            app.NumberofChannelsEditField = uieditfield(app.MainOperationsTab, 'text');
            app.NumberofChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofChannelsEditFieldValueChanged, true);
            app.UITable = uitable(app.MainOperationsTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DevicesLabel = uilabel(app.MainOperationsTab);
            app.DevicesLabel.Text = '------------------------------ Devices ----------------------------';
            app.Spec_freqEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freqEditFieldLabel.Text = 'Spec_freq';
            app.Spec_freqEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_freqEditField.Value = '100,1000,10000';
            app.Spec_freq_xEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_xEditFieldLabel.Text = 'Spec_freq_x';
            app.Spec_freq_xEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_freq_xEditField.Value = '100,1000,10000';
            app.Spec_freq_yEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_yEditFieldLabel.Text = 'Spec_freq_y';
            app.Spec_freq_yEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_freq_yEditField.Value = '100,1000,10000';
            app.Spec_freq_zEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_freq_zEditFieldLabel.Text = 'Spec_freq_z';
            app.Spec_freq_zEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_freq_zEditField.Value = '100,1000,10000';
            app.Spec_SRSEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRSEditFieldLabel.Text = 'Spec_SRS';
            app.Spec_SRSEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_SRSEditField.Value = '10,350,350';
            app.Spec_SRS_xEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRS_xEditFieldLabel.Text = 'Spec_SRS_x';
            app.Spec_SRS_xEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_SRS_xEditField.Value = '10,350,350';
            app.Spec_SRS_yEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRS_yEditFieldLabel.Text = 'Spec_SRS_y';
            app.Spec_SRS_yEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_SRS_yEditField.Value = '10,350,350';
            app.Spec_SRS_zEditFieldLabel = uilabel(app.MainOperationsTab);
            app.Spec_SRS_zEditFieldLabel.Text = 'Spec_SRS_z';
            app.Spec_SRS_zEditField = uieditfield(app.MainOperationsTab, 'text');
            app.Spec_SRS_zEditField.Value = '10,350,350';
            app.IndividualGraphsTab = uitab(app.TabGroup);
            app.IndividualGraphsTab.Title = 'Individual Graphs';
            app.RawDataGraphsTab = uitab(app.TabGroup);
            app.RawDataGraphsTab.Title = 'Raw Data Graphs';
            app.UIAxes_1 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.RawDataGraphsTab);
            title(app.UIAxes_12, 'Title')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.Device1Label_2 = uilabel(app.RawDataGraphsTab);
            app.Device1Label_2.Text = '----------------------------- Device 1 -------------------------';
            app.Device2Label_2 = uilabel(app.RawDataGraphsTab);
            app.Device2Label_2.Text = '----------------------------- Device 2 --------------------------';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shock_Test_App_Unfinished_week3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end